classdef app_source_code < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        InfantMRSUIFigure               matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        PlotMolalityEstimatesButton     matlab.ui.control.Button
        ReferenceCheckBox               matlab.ui.control.CheckBox
        ReferenceCheckBox_2             matlab.ui.control.CheckBox
        ReferenceCheckBox_3             matlab.ui.control.CheckBox
        ReferenceCheckBox_4             matlab.ui.control.CheckBox
        ReferenceCheckBox_5             matlab.ui.control.CheckBox
        ReferenceCheckBox_6             matlab.ui.control.CheckBox
        ReferenceCheckBox_7             matlab.ui.control.CheckBox
        ReferenceCheckBox_8             matlab.ui.control.CheckBox
        ReferenceCheckBox_9             matlab.ui.control.CheckBox
        ReferenceCheckBox_10            matlab.ui.control.CheckBox
        GABAEditFieldLabel              matlab.ui.control.Label
        GABAEditField                   matlab.ui.control.NumericEditField
        tNAAEditFieldLabel              matlab.ui.control.Label
        tNAAEditField                   matlab.ui.control.NumericEditField
        NAAEditFieldLabel               matlab.ui.control.Label
        NAAEditField                    matlab.ui.control.NumericEditField
        tCrEditFieldLabel               matlab.ui.control.Label
        tCrEditField                    matlab.ui.control.NumericEditField
        ChoEditFieldLabel               matlab.ui.control.Label
        ChoEditField                    matlab.ui.control.NumericEditField
        InsEditFieldLabel               matlab.ui.control.Label
        InsEditField                    matlab.ui.control.NumericEditField
        GlxEditFieldLabel               matlab.ui.control.Label
        GlxEditField                    matlab.ui.control.NumericEditField
        GluEditFieldLabel               matlab.ui.control.Label
        GluEditField                    matlab.ui.control.NumericEditField
        GSHEditFieldLabel               matlab.ui.control.Label
        GSHEditField                    matlab.ui.control.NumericEditField
        TauEditFieldLabel               matlab.ui.control.Label
        TauEditField                    matlab.ui.control.NumericEditField
        PostmenstrualAgedaysLabel       matlab.ui.control.Label
        PMAEditField                    matlab.ui.control.NumericEditField
        Switch_2                        matlab.ui.control.Switch
        DatabaseScan1181EditFieldLabel  matlab.ui.control.Label
        UseDatabase                     matlab.ui.control.NumericEditField
        PlotMetaboliteRatiosButton      matlab.ui.control.Button
        PlotEstimatesTrue               matlab.ui.control.Button
        TextArea                        matlab.ui.control.TextArea
        TextArea_2                      matlab.ui.control.TextArea
        RightPanel                      matlab.ui.container.Panel
        PrincipalInterestUIAxes         matlab.ui.control.UIAxes
        PrincipalInterestUIAxes_2       matlab.ui.control.UIAxes
        PrincipalInterestUIAxes_3       matlab.ui.control.UIAxes
        PrincipalInterestUIAxes_4       matlab.ui.control.UIAxes
        PrincipalInterestUIAxes_5       matlab.ui.control.UIAxes
        PrincipalInterestUIAxes_6       matlab.ui.control.UIAxes
        PrincipalInterestUIAxes_7       matlab.ui.control.UIAxes
        PrincipalInterestUIAxes_8       matlab.ui.control.UIAxes
        PrincipalInterestUIAxes_9       matlab.ui.control.UIAxes
        PrincipalInterestUIAxes_10      matlab.ui.control.UIAxes
        GraphsLabel                     matlab.ui.control.TextArea
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.InfantMRSUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {862, 862};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {371, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Button pushed function: PlotMolalityEstimatesButton
        function PlotMolalityEstimatesButtonPushed(app, event)
            app.GraphsLabel.Value = 'Molality Estimates';
            app.GraphsLabel.FontSize = 30;
            
            met_names = {'GABA','tNAA','NAA','tCr','Cho','Ins','Glx','Glu','GSH','Tau'};
            Nmets = 10;
            
            %load normative data
            load(fullfile('InfantData','App_data.mat'));
            Nsubs = size(ald.metw,1);
            
            %assign metabolite signal values:
            if strcmp(app.Switch_2.Value, 'Database')
                gind = find(~isnan(mean(ald.metw,2))); %index of complete data in database
                subind=gind(app.UseDatabase.Value); %identify index of selected subject in the data
                sgn = ald.metw(subind,:); %assign signal values
                PMA = ald.PMA(subind);
            end
            if strcmp(app.Switch_2.Value, 'User-provided data')
                sgn = [app.GABAEditField.Value app.tNAAEditField.Value app.NAAEditField.Value app.tCrEditField.Value app.ChoEditField.Value app.InsEditField.Value app.GlxEditField.Value app.GluEditField.Value app.GSHEditField.Value app.TauEditField.Value];
                PMA = app.PMAEditField.Value;
            end
            
            %identify reference metabolites:
            my_refs = [app.ReferenceCheckBox.Value app.ReferenceCheckBox_2.Value app.ReferenceCheckBox_3.Value app.ReferenceCheckBox_4.Value app.ReferenceCheckBox_5.Value app.ReferenceCheckBox_6.Value app.ReferenceCheckBox_7.Value app.ReferenceCheckBox_8.Value app.ReferenceCheckBox_9.Value app.ReferenceCheckBox_10.Value];
            
            %             %identify options selections
%             if app.UsewfactorsCheckBox.Value == 1
%                 is_weighting = 'y';
%             else
%                 is_weighting = 'n';
%             end
%             if app.AllowselfreferencingCheckBox.Value == 1
%                 is_self = 'y';
%             else
%                 is_self = 'n';
%             end
            is_weighting = 'y';
            is_self = 'n';
            
            mat_refs = NaN*ones(Nmets,Nmets);
            for jj = 1:Nmets
                %calculat a metabolite-specific mask
                mat_refs(jj,:) = my_refs;
                if strcmp(is_self,'n') %no self referencing
                    mat_refs(jj,jj) = 0; %no self-referencing
                    if strcmp(met_names{jj},'Glu') %we don't want to use Glx to reference Glu
                        mat_refs(jj,strcmp(met_names,'Glx')) = 0;
                    end
                    if strcmp(met_names{jj},'NAA') %we don't want to use tNAA to reference NAA
                        mat_refs(jj,strcmp(met_names,'tNAA')) = 0;
                    end
                    if strcmp(met_names{jj},'Glx') %we don't want to use Glx to reference Glu
                        mat_refs(jj,strcmp(met_names,'Glu')) = 0;
                    end
                    if strcmp(met_names{jj},'tNAA') %we don't want to use tNAA to reference NAA
                        mat_refs(jj,strcmp(met_names,'NAA')) = 0;
                    end
                elseif strcmp(is_self,'y') %allow self referencing, nothing to be done
                else
                    error('allow self referencing? y or n')
                end
            end
            
            % %calculate weighting factors:
            metw_wn = NaN*ones(Nmets,Nmets);
            allmets = ones(1,Nmets);
            for jj = 1:Nmets
                msk = logical(mat_refs(jj,:));
                har_mean = 1/mean(1./ald.metwSD(msk));
                if strcmp(is_weighting,'y') %if weighiting factors are to be used:
                    metw_wn(jj,msk) = har_mean./ald.metwSD(msk)/nnz(msk);
                elseif strcmp(is_weighting,'n')
                    metw_wn(jj,msk) = allmets(msk);
                else
                    error('use weighting factors? y or n')
                end
            end
            
            %For the single subject, calculate expected concentration values using
            %polynomial constants obtained from the normative data:
            for jj = 1:Nmets
                C_E(jj) = polyval(ald.metfw_ps(jj,:),PMA,[],ald.mu)';
            end
            
            %for the single subject, calculate R_CRef and C_CRef:
            for jj = 1:Nmets
                msk = logical(mat_refs(jj,:));  %identify reference metabolties
                %R_CReF:
                R_CRef(jj) = sgn(jj)/sum(metw_wn(jj,msk).*sgn(msk));
                %C_CRef:
                C_CRef(jj) = R_CRef(jj).*sum(metw_wn(jj,msk).*C_E(msk));
            end
            
            %For the normative data, calculate R_CRef and C_CRef
            for kk = 1:Nsubs
                for jj = 1:Nmets
                    msk = logical(mat_refs(jj,:));
                    %R_CReF:
                    all_R_CRef(kk,jj) = ald.metw(kk,jj)/sum(metw_wn(jj,msk).*ald.metw(kk,msk));
                    %C_CRef:
                    all_C_CRef(kk,jj) = all_R_CRef(kk,jj).*sum(metw_wn(jj,msk).*ald.metfwE(kk,msk));
                end
            end
            
            %             %for normative data, calcuate average PMA dependence:
            for jj = 1:Nmets
                
                [r,~,~] = find(~isnan(all_R_CRef(:,jj))); %identify NaNs
                %R_CRef
                [ps] = polyfit((ald.PMA(r)-ald.mu(1))/ald.mu(2),all_R_CRef(r,jj),2);
                all_R_CRef_ps(jj,:) = ps;
                all_R_CRef_E(:,jj) = polyval(ps,ald.PMA,[],ald.mu); %expected values for normatived data.
                R_CRef_E(jj) = polyval(ps,PMA,[],ald.mu); %expected value for the single subject.
                
                %C_CRef
                [ps] = polyfit((ald.PMA(r)-ald.mu(1))/ald.mu(2),all_C_CRef(r,jj),2);
                all_C_CRef_ps(jj,:) = ps;
                all_C_CRef_E(:,jj) = polyval(ps,ald.PMA,[],ald.mu); %expected values for normatived data.
                C_CRef_E(jj) = polyval(ps,PMA,[],ald.mu); %expected value for the single subject.
            end
            
            %for normative data, calculate SD values of C_CRef and R_CRef
            for jj = 1:Nmets
                [r,~,~] = find(~isnan(all_R_CRef(:,jj))); %identify NaNs
                R_CRef_SD(jj) = std(all_R_CRef(r,jj) - all_R_CRef_E(r,jj));
                C_CRef_SD(jj) = std(all_C_CRef(r,jj) - all_C_CRef_E(r,jj));
            end
            
            %for normative data, calculate error estimates via comparison of estimated concentrations of normative data with "ground truth" :corrected-water scaled values from a subset of infants
            for jj = 1:Nmets
                C_Cref_error(jj) =nanstd(all_C_CRef(:,jj) - ald.metfw(:,jj));
            end
            
            %calculate percentiles of the single subject, based on group statistics.
            for jj = 1:Nmets
                pct_R_CRef(jj) = round(100*normcdf(R_CRef(jj),R_CRef_E(jj),R_CRef_SD(jj)),0);
                pct_C_CRef(jj) = round(100*normcdf(C_CRef(jj),C_CRef_E(jj),C_CRef_SD(jj)),0);
            end
            
            %Plot the results:
            xs = 260:1:440;% PMA range for continuous plots
            
            % Plot GABA
            jj=1;
            cla(app.PrincipalInterestUIAxes);
            if app.GABAEditField.Value~=0
                
                %plot individuals from the normative data
                plot(app.PrincipalInterestUIAxes,ald.PMA*12/365.25,all_C_CRef(:,jj),'ks','markerfacecolor','w','markersize',3);
                set(app.PrincipalInterestUIAxes,'YMinorTick','on')
                set(app.PrincipalInterestUIAxes,'XMinorTick','on')
                hold(app.PrincipalInterestUIAxes,'on')
                errorbar(app.PrincipalInterestUIAxes,PMA*12/365.25,C_CRef(jj),C_Cref_error(jj),'ko','markerfacecolor','r','markersize',9,'linewidth',2)
                
                
                %plot the curve fits
                ys= polyval(all_C_CRef_ps(jj,:),xs,[],ald.mu);
                plot(app.PrincipalInterestUIAxes,xs*12/365.25,ys,'k-','linewidth',2);
                plot(app.PrincipalInterestUIAxes,xs*12/365.25,ys+C_CRef_SD(jj),'k--','linewidth',2);
                plot(app.PrincipalInterestUIAxes,xs*12/365.25,ys-C_CRef_SD(jj),'k--','linewidth',2);


                set(app.PrincipalInterestUIAxes,'TickLength',[0.03 0.03])
                set(app.PrincipalInterestUIAxes,'Fontsize',11,'linewidth',1.2)
                
                title(app.PrincipalInterestUIAxes,{strcat(met_names{jj},' percentile: ', num2str(pct_C_CRef(jj)),'%')},'fontsize',11)
                ylabel(app.PrincipalInterestUIAxes,strcat(met_names{jj},' (mmol/kg)'),'fontsize',11)
                xlabel(app.PrincipalInterestUIAxes,'PMA (months)','fontsize',11)
                xlim(app.PrincipalInterestUIAxes,[260 440]*12/365.25)
                
                set(app.PrincipalInterestUIAxes,'color','w');
                set(app.PrincipalInterestUIAxes,'Fontsize',11,'linewidth',2)
            end
            % Plot tNAA
            jj=2;
            cla(app.PrincipalInterestUIAxes_2);
            if app.tNAAEditField.Value~=0
                
                %plot individuals from the normative data
                plot(app.PrincipalInterestUIAxes_2,ald.PMA*12/365.25,all_C_CRef(:,jj),'ks','markersize',3)
                set(app.PrincipalInterestUIAxes_2,'YMinorTick','on')
                set(app.PrincipalInterestUIAxes_2,'XMinorTick','on')
                hold(app.PrincipalInterestUIAxes_2,'on')
                errorbar(app.PrincipalInterestUIAxes_2,PMA*12/365.25,C_CRef(jj),C_Cref_error(jj),'ko','markerfacecolor','r','markersize',9,'linewidth',2)
                
                %plot the curve fits
                ys= polyval(all_C_CRef_ps(jj,:),xs,[],ald.mu);
                plot(app.PrincipalInterestUIAxes_2,xs*12/365.25,ys,'k-','linewidth',2);
                plot(app.PrincipalInterestUIAxes_2,xs*12/365.25,ys+C_CRef_SD(jj),'k--','linewidth',2);
                plot(app.PrincipalInterestUIAxes_2,xs*12/365.25,ys-C_CRef_SD(jj),'k--','linewidth',2);


                set(app.PrincipalInterestUIAxes_2,'TickLength',[0.03 0.03])
                set(app.PrincipalInterestUIAxes_2,'Fontsize',11,'linewidth',1.2)
                
                title(app.PrincipalInterestUIAxes_2,{strcat(met_names{jj},' percentile: ', num2str(pct_C_CRef(jj)),'%')},'fontsize',11)
                ylabel(app.PrincipalInterestUIAxes_2,strcat(met_names{jj},' (mmol/kg)'),'fontsize',11)
                xlabel(app.PrincipalInterestUIAxes_2,'PMA (months)','fontsize',11)
                xlim(app.PrincipalInterestUIAxes_2,[260 440]*12/365.25)
                
                set(app.PrincipalInterestUIAxes_2,'color','w');
                set(app.PrincipalInterestUIAxes_2,'Fontsize',11,'linewidth',2)
            end
            % Plot NAA
            jj=3;
            cla(app.PrincipalInterestUIAxes_3);
            if app.NAAEditField.Value~=0
                
                %plot individuals from the normative data
                plot(app.PrincipalInterestUIAxes_3,ald.PMA*12/365.25,all_C_CRef(:,jj),'ks','markersize',3)
                set(app.PrincipalInterestUIAxes_3,'YMinorTick','on')
                set(app.PrincipalInterestUIAxes_3,'XMinorTick','on')
                hold(app.PrincipalInterestUIAxes_3,'on')
                errorbar(app.PrincipalInterestUIAxes_3,PMA*12/365.25,C_CRef(jj),C_Cref_error(jj),'ko','markerfacecolor','r','markersize',9,'linewidth',2)
                
                
                %plot the curve fits
                ys= polyval(all_C_CRef_ps(jj,:),xs,[],ald.mu);
                plot(app.PrincipalInterestUIAxes_3,xs*12/365.25,ys,'k-','linewidth',2);
                plot(app.PrincipalInterestUIAxes_3,xs*12/365.25,ys+C_CRef_SD(jj),'k--','linewidth',2);
                plot(app.PrincipalInterestUIAxes_3,xs*12/365.25,ys-C_CRef_SD(jj),'k--','linewidth',2);


                set(app.PrincipalInterestUIAxes_3,'TickLength',[0.03 0.03])
                set(app.PrincipalInterestUIAxes_3,'Fontsize',11,'linewidth',1.2)
                
                title(app.PrincipalInterestUIAxes_3,{strcat(met_names{jj},' percentile: ', num2str(pct_C_CRef(jj)),'%')},'fontsize',11)
                ylabel(app.PrincipalInterestUIAxes_3,strcat(met_names{jj},' (mmol/kg)'),'fontsize',11)
                xlabel(app.PrincipalInterestUIAxes_3,'PMA (months)','fontsize',11)
                xlim(app.PrincipalInterestUIAxes_3,[260 440]*12/365.25)
                
                set(app.PrincipalInterestUIAxes_3,'color','w');
                set(app.PrincipalInterestUIAxes_3,'Fontsize',11,'linewidth',2)
            end
            % Plot tCr
            jj=4;
            cla(app.PrincipalInterestUIAxes_4);
            if app.tCrEditField.Value~=0
                
                %plot individuals from the normative data
                plot(app.PrincipalInterestUIAxes_4,ald.PMA*12/365.25,all_C_CRef(:,jj),'ks','markersize',3)
                set(app.PrincipalInterestUIAxes_4,'YMinorTick','on')
                set(app.PrincipalInterestUIAxes_4,'XMinorTick','on')
                hold(app.PrincipalInterestUIAxes_4,'on')
                errorbar(app.PrincipalInterestUIAxes_4,PMA*12/365.25,C_CRef(jj),C_Cref_error(jj),'ko','markerfacecolor','r','markersize',9,'linewidth',2)
                
                
                %plot the curve fits
                ys= polyval(all_C_CRef_ps(jj,:),xs,[],ald.mu);
                plot(app.PrincipalInterestUIAxes_4,xs*12/365.25,ys,'k-','linewidth',2);
                plot(app.PrincipalInterestUIAxes_4,xs*12/365.25,ys+C_CRef_SD(jj),'k--','linewidth',2);
                plot(app.PrincipalInterestUIAxes_4,xs*12/365.25,ys-C_CRef_SD(jj),'k--','linewidth',2);
                
                
                set(app.PrincipalInterestUIAxes_4,'TickLength',[0.03 0.03])
                set(app.PrincipalInterestUIAxes_4,'Fontsize',11,'linewidth',1.2)
                
                title(app.PrincipalInterestUIAxes_4,{strcat(met_names{jj},' percentile: ', num2str(pct_C_CRef(jj)),'%')},'fontsize',11)
                ylabel(app.PrincipalInterestUIAxes_4,strcat(met_names{jj},' (mmol/kg)'),'fontsize',11)
                xlabel(app.PrincipalInterestUIAxes_4,'PMA (months)','fontsize',11)
                xlim(app.PrincipalInterestUIAxes_4,[260 440]*12/365.25)
                
                set(app.PrincipalInterestUIAxes_4,'color','w');
                set(app.PrincipalInterestUIAxes_4,'Fontsize',11,'linewidth',2)
            end
            % Plot Cho
            jj=5;
            cla(app.PrincipalInterestUIAxes_5);
            if app.ChoEditField.Value~=0
                %plot individuals from the normative data
                plot(app.PrincipalInterestUIAxes_5,ald.PMA*12/365.25,all_C_CRef(:,jj),'ks','markersize',3)
                set(app.PrincipalInterestUIAxes_5,'YMinorTick','on')
                set(app.PrincipalInterestUIAxes_5,'XMinorTick','on')
                hold(app.PrincipalInterestUIAxes_5,'on')
                errorbar(app.PrincipalInterestUIAxes_5,PMA*12/365.25,C_CRef(jj),C_Cref_error(jj),'ko','markerfacecolor','r','markersize',9,'linewidth',2)
                
                %plot the curve fits
                ys= polyval(all_C_CRef_ps(jj,:),xs,[],ald.mu);
                plot(app.PrincipalInterestUIAxes_5,xs*12/365.25,ys,'k-','linewidth',2);
                plot(app.PrincipalInterestUIAxes_5,xs*12/365.25,ys+C_CRef_SD(jj),'k--','linewidth',2);
                plot(app.PrincipalInterestUIAxes_5,xs*12/365.25,ys-C_CRef_SD(jj),'k--','linewidth',2);


                set(app.PrincipalInterestUIAxes_5,'TickLength',[0.03 0.03])
                set(app.PrincipalInterestUIAxes_5,'Fontsize',11,'linewidth',1.2)
                
                title(app.PrincipalInterestUIAxes_5,{strcat(met_names{jj},' percentile: ', num2str(pct_C_CRef(jj)),'%')},'fontsize',11)
                ylabel(app.PrincipalInterestUIAxes_5,strcat(met_names{jj},' (mmol/kg)'),'fontsize',11)
                xlabel(app.PrincipalInterestUIAxes_5,'PMA (months)','fontsize',11)
                xlim(app.PrincipalInterestUIAxes_5,[260 440]*12/365.25)
                
                set(app.PrincipalInterestUIAxes_5,'color','w');
                set(app.PrincipalInterestUIAxes_5,'Fontsize',11,'linewidth',2)
            end
            jj=6;
            cla(app.PrincipalInterestUIAxes_6);
            if app.InsEditField.Value~=0
                %plot individuals from the normative data
                plot(app.PrincipalInterestUIAxes_6,ald.PMA*12/365.25,all_C_CRef(:,jj),'ks','markersize',3)
                set(app.PrincipalInterestUIAxes_6,'YMinorTick','on')
                set(app.PrincipalInterestUIAxes_6,'XMinorTick','on')
                hold(app.PrincipalInterestUIAxes_6,'on')
                errorbar(app.PrincipalInterestUIAxes_6,PMA*12/365.25,C_CRef(jj),C_Cref_error(jj),'ko','markerfacecolor','r','markersize',9,'linewidth',2)
                
                %plot the curve fits
                ys= polyval(all_C_CRef_ps(jj,:),xs,[],ald.mu);
                plot(app.PrincipalInterestUIAxes_6,xs*12/365.25,ys,'k-','linewidth',2);
                plot(app.PrincipalInterestUIAxes_6,xs*12/365.25,ys+C_CRef_SD(jj),'k--','linewidth',2);
                plot(app.PrincipalInterestUIAxes_6,xs*12/365.25,ys-C_CRef_SD(jj),'k--','linewidth',2);


                set(app.PrincipalInterestUIAxes_6,'TickLength',[0.03 0.03])
                set(app.PrincipalInterestUIAxes_6,'Fontsize',11,'linewidth',1.2)
                
                title(app.PrincipalInterestUIAxes_6,{strcat(met_names{jj},' percentile: ', num2str(pct_C_CRef(jj)),'%')},'fontsize',11)
                ylabel(app.PrincipalInterestUIAxes_6,strcat(met_names{jj},' (mmol/kg)'),'fontsize',11)
                xlabel(app.PrincipalInterestUIAxes_6,'PMA (months)','fontsize',11)
                xlim(app.PrincipalInterestUIAxes_6,[260 440]*12/365.25)
                
                set(app.PrincipalInterestUIAxes_6,'color','w');
                set(app.PrincipalInterestUIAxes_6,'Fontsize',11,'linewidth',2)
            end
            jj=7;
            cla(app.PrincipalInterestUIAxes_7);
            if app.GlxEditField.Value~=0
                %plot individuals from the normative data
                plot(app.PrincipalInterestUIAxes_7,ald.PMA*12/365.25,all_C_CRef(:,jj),'ks','markersize',3)
                set(app.PrincipalInterestUIAxes_7,'YMinorTick','on')
                set(app.PrincipalInterestUIAxes_7,'XMinorTick','on')
                hold(app.PrincipalInterestUIAxes_7,'on')
                errorbar(app.PrincipalInterestUIAxes_7,PMA*12/365.25,C_CRef(jj),C_Cref_error(jj),'ko','markerfacecolor','r','markersize',9,'linewidth',2)
                
                
                %plot the curve fits
                ys= polyval(all_C_CRef_ps(jj,:),xs,[],ald.mu);
                plot(app.PrincipalInterestUIAxes_7,xs*12/365.25,ys,'k-','linewidth',2);
                plot(app.PrincipalInterestUIAxes_7,xs*12/365.25,ys+C_CRef_SD(jj),'k--','linewidth',2);
                plot(app.PrincipalInterestUIAxes_7,xs*12/365.25,ys-C_CRef_SD(jj),'k--','linewidth',2);


                set(app.PrincipalInterestUIAxes_7,'TickLength',[0.03 0.03])
                set(app.PrincipalInterestUIAxes_7,'Fontsize',11,'linewidth',1.2)
                
                title(app.PrincipalInterestUIAxes_7,{strcat(met_names{jj},' percentile: ', num2str(pct_C_CRef(jj)),'%')},'fontsize',11)
                ylabel(app.PrincipalInterestUIAxes_7,strcat(met_names{jj},' (mmol/kg)'),'fontsize',11)
                xlabel(app.PrincipalInterestUIAxes_7,'PMA (months)','fontsize',11)
                xlim(app.PrincipalInterestUIAxes_7,[260 440]*12/365.25)
                
                set(app.PrincipalInterestUIAxes_7,'color','w');
                set(app.PrincipalInterestUIAxes_7,'Fontsize',11,'linewidth',2)
            end
            jj=8;
            cla(app.PrincipalInterestUIAxes_8);
            if app.GluEditField.Value~=0
                %plot individuals from the normative data
                plot(app.PrincipalInterestUIAxes_8,ald.PMA*12/365.25,all_C_CRef(:,jj),'ks','markersize',3);
                set(app.PrincipalInterestUIAxes_8,'YMinorTick','on')
                set(app.PrincipalInterestUIAxes_8,'XMinorTick','on')
                hold(app.PrincipalInterestUIAxes_8,'on')
                errorbar(app.PrincipalInterestUIAxes_8,PMA*12/365.25,C_CRef(jj),C_Cref_error(jj),'ko','markerfacecolor','r','markersize',9,'linewidth',2)
                
                
                %plot the curve fits
                ys= polyval(all_C_CRef_ps(jj,:),xs,[],ald.mu);
                plot(app.PrincipalInterestUIAxes_8,xs*12/365.25,ys,'k-','linewidth',2);
                plot(app.PrincipalInterestUIAxes_8,xs*12/365.25,ys+C_CRef_SD(jj),'k--','linewidth',2);
                plot(app.PrincipalInterestUIAxes_8,xs*12/365.25,ys-C_CRef_SD(jj),'k--','linewidth',2);


                set(app.PrincipalInterestUIAxes_8,'TickLength',[0.03 0.03])
                set(app.PrincipalInterestUIAxes_8,'Fontsize',11,'linewidth',1.2)
                
                title(app.PrincipalInterestUIAxes_8,{strcat(met_names{jj},' percentile: ', num2str(pct_C_CRef(jj)),'%')},'fontsize',11)
                ylabel(app.PrincipalInterestUIAxes_8,strcat(met_names{jj},' (mmol/kg)'),'fontsize',11)
                xlabel(app.PrincipalInterestUIAxes_8,'PMA (months)','fontsize',11)
                xlim(app.PrincipalInterestUIAxes_8,[260 440]*12/365.25)
                
                set(app.PrincipalInterestUIAxes_8,'color','w');
                set(app.PrincipalInterestUIAxes_8,'Fontsize',11,'linewidth',2)
            end
            jj=9;
            cla(app.PrincipalInterestUIAxes_9);
            if app.GSHEditField.Value~=0
                errorbar(app.PrincipalInterestUIAxes_9,PMA*12/365.25,C_CRef(jj),C_Cref_error(jj),'ko','markerfacecolor','r','markersize',9,'linewidth',2)
                set(app.PrincipalInterestUIAxes_9,'YMinorTick','on')
                set(app.PrincipalInterestUIAxes_9,'XMinorTick','on')
                hold(app.PrincipalInterestUIAxes_9,'on')
                
                %plot the curve fits
                ys= polyval(all_C_CRef_ps(jj,:),xs,[],ald.mu);
                plot(app.PrincipalInterestUIAxes_9,xs*12/365.25,ys,'k-','linewidth',2);
                plot(app.PrincipalInterestUIAxes_9,xs*12/365.25,ys+C_CRef_SD(jj),'k--','linewidth',2);
                plot(app.PrincipalInterestUIAxes_9,xs*12/365.25,ys-C_CRef_SD(jj),'k--','linewidth',2);
                
                %plot individuals from the normative data
                plot(app.PrincipalInterestUIAxes_9,ald.PMA*12/365.25,all_C_CRef(:,jj),'ks','markersize',3)
                
                set(app.PrincipalInterestUIAxes_9,'TickLength',[0.03 0.03])
                set(app.PrincipalInterestUIAxes_9,'Fontsize',11,'linewidth',1.2)
                
                title(app.PrincipalInterestUIAxes_9,{strcat(met_names{jj},' percentile: ', num2str(pct_C_CRef(jj)),'%')},'fontsize',11)
                ylabel(app.PrincipalInterestUIAxes_9,strcat(met_names{jj},' (mmol/kg)'),'fontsize',11)
                xlabel(app.PrincipalInterestUIAxes_9,'PMA (months)','fontsize',11)
                xlim(app.PrincipalInterestUIAxes_9,[260 440]*12/365.25)
                
                set(app.PrincipalInterestUIAxes_9,'color','w');
                set(app.PrincipalInterestUIAxes_9,'Fontsize',11,'linewidth',2)
            end
            jj=10;
            cla(app.PrincipalInterestUIAxes_10);
            if app.TauEditField.Value~=0
                errorbar(app.PrincipalInterestUIAxes_10,PMA*12/365.25,C_CRef(jj),C_Cref_error(jj),'ko','markerfacecolor','r','markersize',9,'linewidth',2)
                set(app.PrincipalInterestUIAxes_10,'YMinorTick','on')
                set(app.PrincipalInterestUIAxes_10,'XMinorTick','on')
                hold(app.PrincipalInterestUIAxes_10,'on')
                
                %plot the curve fits
                ys= polyval(all_C_CRef_ps(jj,:),xs,[],ald.mu);
                plot(app.PrincipalInterestUIAxes_10,xs*12/365.25,ys,'k-','linewidth',2);
                plot(app.PrincipalInterestUIAxes_10,xs*12/365.25,ys+C_CRef_SD(jj),'k--','linewidth',2);
                plot(app.PrincipalInterestUIAxes_10,xs*12/365.25,ys-C_CRef_SD(jj),'k--','linewidth',2);
                
                %plot individuals from the normative data
                plot(app.PrincipalInterestUIAxes_10,ald.PMA*12/365.25,all_C_CRef(:,jj),'ks','markersize',3)
                
                set(app.PrincipalInterestUIAxes_10,'TickLength',[0.03 0.03])
                set(app.PrincipalInterestUIAxes_10,'Fontsize',11,'linewidth',1.2)
                
                title(app.PrincipalInterestUIAxes_10,{strcat(met_names{jj},' percentile: ', num2str(pct_C_CRef(jj)),'%')},'fontsize',11)
                ylabel(app.PrincipalInterestUIAxes_10,strcat(met_names{jj},' (mmol/kg)'),'fontsize',11)
                xlabel(app.PrincipalInterestUIAxes_10,'PMA (months)','fontsize',11)
                xlim(app.PrincipalInterestUIAxes_10,[260 440]*12/365.25)
                
                set(app.PrincipalInterestUIAxes_10,'color','w');
                set(app.PrincipalInterestUIAxes_10,'Fontsize',11,'linewidth',2)
            end
            
        end

        % Value changed function: Switch_2
        function Switch_2ValueChanged(app, event)
            if strcmp(app.Switch_2.Value, 'Database')
                app.UseDatabase.Editable = 'on';
                app.PMAEditField.Editable = 'off';
                app.GABAEditField.Editable = 'off';
                app.tNAAEditField.Editable = 'off';
                app.NAAEditField.Editable = 'off';
                app.tCrEditField.Editable = 'off';
                app.ChoEditField.Editable = 'off';
                app.InsEditField.Editable = 'off';
                app.GlxEditField.Editable = 'off';
                app.GluEditField.Editable = 'off';
                app.GSHEditField.Editable = 'off';
                app.TauEditField.Editable = 'off';
                if app.UseDatabase.Value<=71
                    app.PlotEstimatesTrue.Enable = 'on';
                else
                    app.PlotEstimatesTrue.Enable = 'off';
                end
                ASDF=5;
            end
            if strcmp(app.Switch_2.Value, 'User-provided data')
                app.UseDatabase.Editable = 'off';
                app.PMAEditField.Editable = 'on';
                app.GABAEditField.Editable = 'on';
                app.tNAAEditField.Editable = 'on';
                app.NAAEditField.Editable = 'on';
                app.tCrEditField.Editable = 'on';
                app.ChoEditField.Editable = 'on';
                app.InsEditField.Editable = 'on';
                app.GlxEditField.Editable = 'on';
                app.GluEditField.Editable = 'on';
                app.GSHEditField.Editable = 'on';
                app.TauEditField.Editable = 'on';
                app.PlotEstimatesTrue.Enable = 'off';
                asdf=5
            end
        end

        % Value changed function: UseDatabase
        function UseDatabaseValueChanged(app, event)
            load(fullfile('InfantData','App_data.mat'))
            gind = find(~isnan(mean(ald.metw,2))); %index of complete data in database
            subind=gind(app.UseDatabase.Value); %identify index of selected subject in the data
            app.GABAEditField.Value =ald.metw(subind,1);
            app.tNAAEditField.Value =ald.metw(subind,2);
            app.NAAEditField.Value =ald.metw(subind,3);
            app.tCrEditField.Value =ald.metw(subind,4);
            app.ChoEditField.Value =ald.metw(subind,5);
            app.InsEditField.Value =ald.metw(subind,6);
            app.GlxEditField.Value =ald.metw(subind,7);
            app.GluEditField.Value =ald.metw(subind,8);
            app.GSHEditField.Value =ald.metw(subind,9);
            app.TauEditField.Value =ald.metw(subind,10);
            app.PMAEditField.Value =ald.PMA(subind);
   
            if app.UseDatabase.Value<=71
                app.PlotEstimatesTrue.Enable = 'on';
            else
                app.PlotEstimatesTrue.Enable = 'off';
            end
        end

        % Button pushed function: PlotMetaboliteRatiosButton
        function PlotMetaboliteRatiosButtonPushed(app, event)
            met_names = {'GABA','tNAA','NAA','tCr','Cho','Ins','Glx','Glu','GSH','Tau'};
            Nmets = 10;
            app.GraphsLabel.Value = 'Metabolite Ratios';
            app.GraphsLabel.FontSize = 30;
            %load normative data
            load(fullfile('InfantData','App_data.mat'));
            Nsubs = size(ald.metw,1);
            
            %assign metabolite signal values:
            if strcmp(app.Switch_2.Value, 'Database')
                gind = find(~isnan(mean(ald.metw,2))); %index of complete data in database
                subind=gind(app.UseDatabase.Value); %identify index of selected subject in the data
                sgn = ald.metw(subind,:); %assign signal values
                PMA = ald.PMA(subind);
            end
            if strcmp(app.Switch_2.Value, 'User-provided data')
                sgn = [app.GABAEditField.Value app.tNAAEditField.Value app.NAAEditField.Value app.tCrEditField.Value app.ChoEditField.Value app.InsEditField.Value app.GlxEditField.Value app.GluEditField.Value app.GSHEditField.Value app.TauEditField.Value];
                PMA = app.PMAEditField.Value;
            end
            
            %identify reference metabolites:
            my_refs = [app.ReferenceCheckBox.Value app.ReferenceCheckBox_2.Value app.ReferenceCheckBox_3.Value app.ReferenceCheckBox_4.Value app.ReferenceCheckBox_5.Value app.ReferenceCheckBox_6.Value app.ReferenceCheckBox_7.Value app.ReferenceCheckBox_8.Value app.ReferenceCheckBox_9.Value app.ReferenceCheckBox_10.Value];
            
%             %identify options selections
%             if app.UsewfactorsCheckBox.Value == 1
%                 is_weighting = 'y';
%             else
%                 is_weighting = 'n';
%             end
%             if app.AllowselfreferencingCheckBox.Value == 1
%                 is_self = 'y';
%             else
%                 is_self = 'n';
%             end
            is_weighting = 'y';
            is_self = 'n';
            
            mat_refs = NaN*ones(Nmets,Nmets);
            for jj = 1:Nmets
                %calculat a metabolite-specific mask
                mat_refs(jj,:) = my_refs;
                if strcmp(is_self,'n') %no self referencing
                    mat_refs(jj,jj) = 0; %no self-referencing
                    if strcmp(met_names{jj},'Glu') %we don't want to use Glx to reference Glu
                        mat_refs(jj,strcmp(met_names,'Glx')) = 0;
                    end
                    if strcmp(met_names{jj},'NAA') %we don't want to use tNAA to reference NAA
                        mat_refs(jj,strcmp(met_names,'tNAA')) = 0;
                    end
                    if strcmp(met_names{jj},'Glx') %we don't want to use Glx to reference Glu
                        mat_refs(jj,strcmp(met_names,'Glu')) = 0;
                    end
                    if strcmp(met_names{jj},'tNAA') %we don't want to use tNAA to reference NAA
                        mat_refs(jj,strcmp(met_names,'NAA')) = 0;
                    end
                elseif strcmp(is_self,'y') %allow self referencing, nothing to be done
                else
                    error('allow self referencing? y or n')
                end
            end
            
            % %calculate weighting factors:
            metw_wn = NaN*ones(Nmets,Nmets);
            allmets = ones(1,Nmets);
            for jj = 1:Nmets
                msk = logical(mat_refs(jj,:));
                har_mean = 1/mean(1./ald.metwSD(msk));
                if strcmp(is_weighting,'y') %if weighiting factors are to be used:
                    metw_wn(jj,msk) = har_mean./ald.metwSD(msk)/nnz(msk);
                elseif strcmp(is_weighting,'n')
                    metw_wn(jj,msk) = allmets(msk);
                else
                    error('use weighting factors? y or n')
                end
            end
            
            %For the single subject, calculate expected concentration values using
            %polynomial constants obtained from the normative data:
            for jj = 1:Nmets
                C_E(jj) = polyval(ald.metfw_ps(jj,:),PMA,[],ald.mu)';
            end
            
            %for the single subject, calculate R_CRef and C_CRef:
            for jj = 1:Nmets
                msk = logical(mat_refs(jj,:));  %identify reference metabolties
                %R_CReF:
                R_CRef(jj) = sgn(jj)/sum(metw_wn(jj,msk).*sgn(msk));
                %C_CRef:
                C_CRef(jj) = R_CRef(jj).*sum(metw_wn(jj,msk).*C_E(msk));
            end
            
            %For the normative data, calculate R_CRef and C_CRef
            for kk = 1:Nsubs
                for jj = 1:Nmets
                    msk = logical(mat_refs(jj,:));
                    %R_CReF:
                    all_R_CRef(kk,jj) = ald.metw(kk,jj)/sum(metw_wn(jj,msk).*ald.metw(kk,msk));
                    %C_CRef:
                    all_C_CRef(kk,jj) = all_R_CRef(kk,jj).*sum(metw_wn(jj,msk).*ald.metfwE(kk,msk));
                end
            end
            
            %             %for normative data, calcuate average PMA dependence:
            for jj = 1:Nmets
                
                [r,~,~] = find(~isnan(all_R_CRef(:,jj))); %identify NaNs
                %R_CRef
                [ps] = polyfit((ald.PMA(r)-ald.mu(1))/ald.mu(2),all_R_CRef(r,jj),2);
                all_R_CRef_ps(jj,:) = ps;
                all_R_CRef_E(:,jj) = polyval(ps,ald.PMA,[],ald.mu); %expected values for normatived data.
                R_CRef_E(jj) = polyval(ps,PMA,[],ald.mu); %expected value for the single subject.
                
                %C_CRef
                [ps] = polyfit((ald.PMA(r)-ald.mu(1))/ald.mu(2),all_C_CRef(r,jj),2);
                all_C_CRef_ps(jj,:) = ps;
                all_C_CRef_E(:,jj) = polyval(ps,ald.PMA,[],ald.mu); %expected values for normatived data.
                C_CRef_E(jj) = polyval(ps,PMA,[],ald.mu); %expected value for the single subject.
            end
            
            %for normative data, calculate SD values of C_CRef and R_CRef
            for jj = 1:Nmets
                [r,~,~] = find(~isnan(all_R_CRef(:,jj))); %identify NaNs
                R_CRef_SD(jj) = std(all_R_CRef(r,jj) - all_R_CRef_E(r,jj));
                C_CRef_SD(jj) = std(all_C_CRef(r,jj) - all_C_CRef_E(r,jj));
            end
            
            %for normative data, calculate error estimates via comparison of estimated concentrations of normative data with "ground truth" :corrected-water scaled values from a subset of infants
            for jj = 1:Nmets
                C_Cref_error(jj) =nanstd(all_C_CRef(:,jj) - ald.metfw(:,jj));
            end
            
            %calculate percentiles of the single subject, based on group statistics.
            for jj = 1:Nmets
                pct_R_CRef(jj) = round(100*normcdf(R_CRef(jj),R_CRef_E(jj),R_CRef_SD(jj)),0);
                pct_C_CRef(jj) = round(100*normcdf(C_CRef(jj),C_CRef_E(jj),C_CRef_SD(jj)),0);
            end
            
            %Plot the results:
            xs = 260:1:440;% PMA range for continuous plots
            
            % Plot GABA
            jj=1;
            cla(app.PrincipalInterestUIAxes);
            if app.GABAEditField.Value~=0
                
                %plot individuals from the normative data
                plot(app.PrincipalInterestUIAxes,ald.PMA*12/365.25,all_R_CRef(:,jj),'ks','markersize',3)
                set(app.PrincipalInterestUIAxes,'YMinorTick','on')
                set(app.PrincipalInterestUIAxes,'XMinorTick','on')
                hold(app.PrincipalInterestUIAxes,'on')
                plot(app.PrincipalInterestUIAxes,PMA*12/365.25,R_CRef(jj),'ko','markerfacecolor','r','markersize',9,'linewidth',2)
                
                %plot the curve fits
                ys= polyval(all_R_CRef_ps(jj,:),xs,[],ald.mu);
                plot(app.PrincipalInterestUIAxes,xs*12/365.25,ys,'k-','linewidth',2);
                plot(app.PrincipalInterestUIAxes,xs*12/365.25,ys+R_CRef_SD(jj),'k--','linewidth',2);
                plot(app.PrincipalInterestUIAxes,xs*12/365.25,ys-R_CRef_SD(jj),'k--','linewidth',2);


                set(app.PrincipalInterestUIAxes,'TickLength',[0.03 0.03])
                set(app.PrincipalInterestUIAxes,'Fontsize',11,'linewidth',1.2)
                
                title(app.PrincipalInterestUIAxes,{strcat(met_names{jj},' percentile: ', num2str(pct_R_CRef(jj)),'%')},'fontsize',11)
                ylabel(app.PrincipalInterestUIAxes,strcat(met_names{jj}),'fontsize',11)
                xlabel(app.PrincipalInterestUIAxes,'PMA (months)','fontsize',11)
                xlim(app.PrincipalInterestUIAxes,[260 440]*12/365.25)
                
                set(app.PrincipalInterestUIAxes,'color','w');
                set(app.PrincipalInterestUIAxes,'Fontsize',11,'linewidth',2)
            end
            % Plot tNAA
            jj=2;
            cla(app.PrincipalInterestUIAxes_2);
            if app.tNAAEditField.Value~=0
                
                %plot individuals from the normative data
                plot(app.PrincipalInterestUIAxes_2,ald.PMA*12/365.25,all_R_CRef(:,jj),'ks','markersize',3)
                set(app.PrincipalInterestUIAxes_2,'YMinorTick','on')
                set(app.PrincipalInterestUIAxes_2,'XMinorTick','on')
                hold(app.PrincipalInterestUIAxes_2,'on')
                plot(app.PrincipalInterestUIAxes_2,PMA*12/365.25,R_CRef(jj),'ko','markerfacecolor','r','markersize',9,'linewidth',2)
                
                %plot the curve fits
                ys= polyval(all_R_CRef_ps(jj,:),xs,[],ald.mu);
                plot(app.PrincipalInterestUIAxes_2,xs*12/365.25,ys,'k-','linewidth',2);
                plot(app.PrincipalInterestUIAxes_2,xs*12/365.25,ys+R_CRef_SD(jj),'k--','linewidth',2);
                plot(app.PrincipalInterestUIAxes_2,xs*12/365.25,ys-R_CRef_SD(jj),'k--','linewidth',2);


                set(app.PrincipalInterestUIAxes_2,'TickLength',[0.03 0.03])
                set(app.PrincipalInterestUIAxes_2,'Fontsize',11,'linewidth',1.2)
                
                title(app.PrincipalInterestUIAxes_2,{strcat(met_names{jj},' percentile: ', num2str(pct_R_CRef(jj)),'%')},'fontsize',11)
                ylabel(app.PrincipalInterestUIAxes_2,strcat(met_names{jj}),'fontsize',11)
                xlabel(app.PrincipalInterestUIAxes_2,'PMA (months)','fontsize',11)
                xlim(app.PrincipalInterestUIAxes_2,[260 440]*12/365.25)
                
                set(app.PrincipalInterestUIAxes_2,'color','w');
                set(app.PrincipalInterestUIAxes_2,'Fontsize',11,'linewidth',2)
            end
            % Plot NAA
            jj=3;
            cla(app.PrincipalInterestUIAxes_3);
            if app.NAAEditField.Value~=0
                
                %plot individuals from the normative data
                plot(app.PrincipalInterestUIAxes_3,ald.PMA*12/365.25,all_R_CRef(:,jj),'ks','markersize',3)
                set(app.PrincipalInterestUIAxes_3,'YMinorTick','on')
                set(app.PrincipalInterestUIAxes_3,'XMinorTick','on')
                hold(app.PrincipalInterestUIAxes_3,'on')
                plot(app.PrincipalInterestUIAxes_3,PMA*12/365.25,R_CRef(jj),'ko','markerfacecolor','r','markersize',9,'linewidth',2)
                
                %plot the curve fits
                ys= polyval(all_R_CRef_ps(jj,:),xs,[],ald.mu);
                plot(app.PrincipalInterestUIAxes_3,xs*12/365.25,ys,'k-','linewidth',2);
                plot(app.PrincipalInterestUIAxes_3,xs*12/365.25,ys+R_CRef_SD(jj),'k--','linewidth',2);
                plot(app.PrincipalInterestUIAxes_3,xs*12/365.25,ys-R_CRef_SD(jj),'k--','linewidth',2);


                set(app.PrincipalInterestUIAxes_3,'TickLength',[0.03 0.03])
                set(app.PrincipalInterestUIAxes_3,'Fontsize',11,'linewidth',1.2)
                
                title(app.PrincipalInterestUIAxes_3,{strcat(met_names{jj},' percentile: ', num2str(pct_R_CRef(jj)),'%')},'fontsize',11)
                ylabel(app.PrincipalInterestUIAxes_3,strcat(met_names{jj}),'fontsize',11)
                xlabel(app.PrincipalInterestUIAxes_3,'PMA (months)','fontsize',11)
                xlim(app.PrincipalInterestUIAxes_3,[260 440]*12/365.25)
                
                set(app.PrincipalInterestUIAxes_3,'color','w');
                set(app.PrincipalInterestUIAxes_3,'Fontsize',11,'linewidth',2)
            end
            % Plot tCr
            jj=4;
            cla(app.PrincipalInterestUIAxes_4);
            if app.tCrEditField.Value~=0
                
                %plot individuals from the normative data
                plot(app.PrincipalInterestUIAxes_4,ald.PMA*12/365.25,all_R_CRef(:,jj),'ks','markersize',3)
                set(app.PrincipalInterestUIAxes_4,'YMinorTick','on')
                set(app.PrincipalInterestUIAxes_4,'XMinorTick','on')
                hold(app.PrincipalInterestUIAxes_4,'on')
                plot(app.PrincipalInterestUIAxes_4,PMA*12/365.25,R_CRef(jj),'ko','markerfacecolor','r','markersize',9,'linewidth',2)
                
                %plot the curve fits
                ys= polyval(all_R_CRef_ps(jj,:),xs,[],ald.mu);
                plot(app.PrincipalInterestUIAxes_4,xs*12/365.25,ys,'k-','linewidth',2);
                plot(app.PrincipalInterestUIAxes_4,xs*12/365.25,ys+R_CRef_SD(jj),'k--','linewidth',2);
                plot(app.PrincipalInterestUIAxes_4,xs*12/365.25,ys-R_CRef_SD(jj),'k--','linewidth',2);


                set(app.PrincipalInterestUIAxes_4,'TickLength',[0.03 0.03])
                set(app.PrincipalInterestUIAxes_4,'Fontsize',11,'linewidth',1.2)
                
                title(app.PrincipalInterestUIAxes_4,{strcat(met_names{jj},' percentile: ', num2str(pct_R_CRef(jj)),'%')},'fontsize',11)
                ylabel(app.PrincipalInterestUIAxes_4,strcat(met_names{jj}),'fontsize',11)
                xlabel(app.PrincipalInterestUIAxes_4,'PMA (months)','fontsize',11)
                xlim(app.PrincipalInterestUIAxes_4,[260 440]*12/365.25)
                
                set(app.PrincipalInterestUIAxes_4,'color','w');
                set(app.PrincipalInterestUIAxes_4,'Fontsize',11,'linewidth',2)
            end
            % Plot Cho
            jj=5;
            cla(app.PrincipalInterestUIAxes_5);
            if app.ChoEditField.Value~=0
                
                %plot individuals from the normative data
                plot(app.PrincipalInterestUIAxes_5,ald.PMA*12/365.25,all_R_CRef(:,jj),'ks','markersize',3)
                set(app.PrincipalInterestUIAxes_5,'YMinorTick','on')
                set(app.PrincipalInterestUIAxes_5,'XMinorTick','on')
                hold(app.PrincipalInterestUIAxes_5,'on')
                plot(app.PrincipalInterestUIAxes_5,PMA*12/365.25,R_CRef(jj),'ko','markerfacecolor','r','markersize',9,'linewidth',2)
                
                %plot the curve fits
                ys= polyval(all_R_CRef_ps(jj,:),xs,[],ald.mu);
                plot(app.PrincipalInterestUIAxes_5,xs*12/365.25,ys,'k-','linewidth',2);
                plot(app.PrincipalInterestUIAxes_5,xs*12/365.25,ys+R_CRef_SD(jj),'k--','linewidth',2);
                plot(app.PrincipalInterestUIAxes_5,xs*12/365.25,ys-R_CRef_SD(jj),'k--','linewidth',2);
                               
                
                set(app.PrincipalInterestUIAxes_5,'TickLength',[0.03 0.03])
                set(app.PrincipalInterestUIAxes_5,'Fontsize',11,'linewidth',1.2)
                
                title(app.PrincipalInterestUIAxes_5,{strcat(met_names{jj},' percentile: ', num2str(pct_R_CRef(jj)),'%')},'fontsize',11)
                ylabel(app.PrincipalInterestUIAxes_5,strcat(met_names{jj}),'fontsize',11)
                xlabel(app.PrincipalInterestUIAxes_5,'PMA (months)','fontsize',11)
                xlim(app.PrincipalInterestUIAxes_5,[260 440]*12/365.25)
                
                set(app.PrincipalInterestUIAxes_5,'color','w');
                set(app.PrincipalInterestUIAxes_5,'Fontsize',11,'linewidth',2)
            end
            jj=6;
            cla(app.PrincipalInterestUIAxes_6);
            if app.InsEditField.Value~=0
                
                %plot individuals from the normative data
                plot(app.PrincipalInterestUIAxes_6,ald.PMA*12/365.25,all_R_CRef(:,jj),'ks','markersize',3)
                set(app.PrincipalInterestUIAxes_6,'YMinorTick','on')
                set(app.PrincipalInterestUIAxes_6,'XMinorTick','on')
                hold(app.PrincipalInterestUIAxes_6,'on')
                plot(app.PrincipalInterestUIAxes_6,PMA*12/365.25,R_CRef(jj),'ko','markerfacecolor','r','markersize',9,'linewidth',2)
                
                %plot the curve fits
                ys= polyval(all_R_CRef_ps(jj,:),xs,[],ald.mu);
                plot(app.PrincipalInterestUIAxes_6,xs*12/365.25,ys,'k-','linewidth',2);
                plot(app.PrincipalInterestUIAxes_6,xs*12/365.25,ys+R_CRef_SD(jj),'k--','linewidth',2);
                plot(app.PrincipalInterestUIAxes_6,xs*12/365.25,ys-R_CRef_SD(jj),'k--','linewidth',2);


                set(app.PrincipalInterestUIAxes_6,'TickLength',[0.03 0.03])
                set(app.PrincipalInterestUIAxes_6,'Fontsize',11,'linewidth',1.2)
                
                title(app.PrincipalInterestUIAxes_6,{strcat(met_names{jj},' percentile: ', num2str(pct_R_CRef(jj)),'%')},'fontsize',11)
                ylabel(app.PrincipalInterestUIAxes_6,strcat(met_names{jj}),'fontsize',11)
                xlabel(app.PrincipalInterestUIAxes_6,'PMA (months)','fontsize',11)
                xlim(app.PrincipalInterestUIAxes_6,[260 440]*12/365.25)
                
                set(app.PrincipalInterestUIAxes_6,'color','w');
                set(app.PrincipalInterestUIAxes_6,'Fontsize',11,'linewidth',2)
            end
            jj=7;
            cla(app.PrincipalInterestUIAxes_7);
            if app.GlxEditField.Value~=0
                
                %plot individuals from the normative data
                plot(app.PrincipalInterestUIAxes_7,ald.PMA*12/365.25,all_R_CRef(:,jj),'ks','markersize',3)
                set(app.PrincipalInterestUIAxes_7,'YMinorTick','on')
                set(app.PrincipalInterestUIAxes_7,'XMinorTick','on')
                hold(app.PrincipalInterestUIAxes_7,'on')
                plot(app.PrincipalInterestUIAxes_7,PMA*12/365.25,R_CRef(jj),'ko','markerfacecolor','r','markersize',9,'linewidth',2)
                
                %plot the curve fits
                ys= polyval(all_R_CRef_ps(jj,:),xs,[],ald.mu);
                plot(app.PrincipalInterestUIAxes_7,xs*12/365.25,ys,'k-','linewidth',2);
                plot(app.PrincipalInterestUIAxes_7,xs*12/365.25,ys+R_CRef_SD(jj),'k--','linewidth',2);
                plot(app.PrincipalInterestUIAxes_7,xs*12/365.25,ys-R_CRef_SD(jj),'k--','linewidth',2);


                set(app.PrincipalInterestUIAxes_7,'TickLength',[0.03 0.03])
                set(app.PrincipalInterestUIAxes_7,'Fontsize',11,'linewidth',1.2)
                
                title(app.PrincipalInterestUIAxes_7,{strcat(met_names{jj},' percentile: ', num2str(pct_R_CRef(jj)),'%')},'fontsize',11)
                ylabel(app.PrincipalInterestUIAxes_7,strcat(met_names{jj}),'fontsize',11)
                xlabel(app.PrincipalInterestUIAxes_7,'PMA (months)','fontsize',11)
                xlim(app.PrincipalInterestUIAxes_7,[260 440]*12/365.25)
                
                set(app.PrincipalInterestUIAxes_7,'color','w');
                set(app.PrincipalInterestUIAxes_7,'Fontsize',11,'linewidth',2)
            end
            jj=8;
            cla(app.PrincipalInterestUIAxes_8);
            if app.GluEditField.Value~=0
                
                %plot individuals from the normative data
                plot(app.PrincipalInterestUIAxes_8,ald.PMA*12/365.25,all_R_CRef(:,jj),'ks','markersize',3)
                set(app.PrincipalInterestUIAxes_8,'YMinorTick','on')
                set(app.PrincipalInterestUIAxes_8,'XMinorTick','on')
                hold(app.PrincipalInterestUIAxes_8,'on')
                plot(app.PrincipalInterestUIAxes_8,PMA*12/365.25,R_CRef(jj),'ko','markerfacecolor','r','markersize',9,'linewidth',2)
                
                %plot the curve fits
                ys= polyval(all_R_CRef_ps(jj,:),xs,[],ald.mu);
                plot(app.PrincipalInterestUIAxes_8,xs*12/365.25,ys,'k-','linewidth',2);
                plot(app.PrincipalInterestUIAxes_8,xs*12/365.25,ys+R_CRef_SD(jj),'k--','linewidth',2);
                plot(app.PrincipalInterestUIAxes_8,xs*12/365.25,ys-R_CRef_SD(jj),'k--','linewidth',2);


                set(app.PrincipalInterestUIAxes_8,'TickLength',[0.03 0.03])
                set(app.PrincipalInterestUIAxes_8,'Fontsize',11,'linewidth',1.2)
                
                title(app.PrincipalInterestUIAxes_8,{strcat(met_names{jj},' percentile: ', num2str(pct_R_CRef(jj)),'%')},'fontsize',11)
                ylabel(app.PrincipalInterestUIAxes_8,strcat(met_names{jj}),'fontsize',11)
                xlabel(app.PrincipalInterestUIAxes_8,'PMA (months)','fontsize',11)
                xlim(app.PrincipalInterestUIAxes_8,[260 440]*12/365.25)
                
                set(app.PrincipalInterestUIAxes_8,'color','w');
                set(app.PrincipalInterestUIAxes_8,'Fontsize',11,'linewidth',2)
            end
            jj=9;
            cla(app.PrincipalInterestUIAxes_9);
            if app.GSHEditField.Value~=0
                
                %plot individuals from the normative data
                plot(app.PrincipalInterestUIAxes_9,ald.PMA*12/365.25,all_R_CRef(:,jj),'ks','markersize',3)
                set(app.PrincipalInterestUIAxes_9,'YMinorTick','on')
                set(app.PrincipalInterestUIAxes_9,'XMinorTick','on')
                hold(app.PrincipalInterestUIAxes_9,'on')
                plot(app.PrincipalInterestUIAxes_9,PMA*12/365.25,R_CRef(jj),'ko','markerfacecolor','r','markersize',9,'linewidth',2)
                
                %plot the curve fits
                ys= polyval(all_R_CRef_ps(jj,:),xs,[],ald.mu);
                plot(app.PrincipalInterestUIAxes_9,xs*12/365.25,ys,'k-','linewidth',2);
                plot(app.PrincipalInterestUIAxes_9,xs*12/365.25,ys+R_CRef_SD(jj),'k--','linewidth',2);
                plot(app.PrincipalInterestUIAxes_9,xs*12/365.25,ys-R_CRef_SD(jj),'k--','linewidth',2);                            
                
                set(app.PrincipalInterestUIAxes_9,'TickLength',[0.03 0.03])
                set(app.PrincipalInterestUIAxes_9,'Fontsize',11,'linewidth',1.2)
                
                title(app.PrincipalInterestUIAxes_9,{strcat(met_names{jj},' percentile: ', num2str(pct_R_CRef(jj)),'%')},'fontsize',11)
                ylabel(app.PrincipalInterestUIAxes_9,strcat(met_names{jj}),'fontsize',11)
                xlabel(app.PrincipalInterestUIAxes_9,'PMA (months)','fontsize',11)
                xlim(app.PrincipalInterestUIAxes_9,[260 440]*12/365.25)
                
                set(app.PrincipalInterestUIAxes_9,'color','w');
                set(app.PrincipalInterestUIAxes_9,'Fontsize',11,'linewidth',2)
            end
            jj=10;
            cla(app.PrincipalInterestUIAxes_10);
            if app.TauEditField.Value~=0
                
                %plot individuals from the normative data
                plot(app.PrincipalInterestUIAxes_10,ald.PMA*12/365.25,all_R_CRef(:,jj),'ks','markersize',3)
                set(app.PrincipalInterestUIAxes_10,'YMinorTick','on')
                set(app.PrincipalInterestUIAxes_10,'XMinorTick','on')
                hold(app.PrincipalInterestUIAxes_10,'on')
                plot(app.PrincipalInterestUIAxes_10,PMA*12/365.25,R_CRef(jj),'ko','markerfacecolor','r','markersize',9,'linewidth',2)
                
                %plot the curve fits
                ys= polyval(all_R_CRef_ps(jj,:),xs,[],ald.mu);
                plot(app.PrincipalInterestUIAxes_10,xs*12/365.25,ys,'k-','linewidth',2);
                plot(app.PrincipalInterestUIAxes_10,xs*12/365.25,ys+R_CRef_SD(jj),'k--','linewidth',2);
                plot(app.PrincipalInterestUIAxes_10,xs*12/365.25,ys-R_CRef_SD(jj),'k--','linewidth',2);


                set(app.PrincipalInterestUIAxes_10,'TickLength',[0.03 0.03])
                set(app.PrincipalInterestUIAxes_10,'Fontsize',11,'linewidth',1.2)
                
                title(app.PrincipalInterestUIAxes_10,{strcat(met_names{jj},' percentile: ', num2str(pct_R_CRef(jj)),'%')},'fontsize',11)
                ylabel(app.PrincipalInterestUIAxes_10,strcat(met_names{jj}),'fontsize',11)
                xlabel(app.PrincipalInterestUIAxes_10,'PMA (months)','fontsize',11)
                xlim(app.PrincipalInterestUIAxes_10,[260 440]*12/365.25)
                
                set(app.PrincipalInterestUIAxes_10,'color','w');
                set(app.PrincipalInterestUIAxes_10,'Fontsize',11,'linewidth',2)
            end
            
        end

        % Value changed function: ReferenceCheckBox_2
        function ReferenceCheckBox_2ValueChanged(app, event)
            if app.ReferenceCheckBox_2.Value==1
                app.ReferenceCheckBox_3.Value= not(app.ReferenceCheckBox_2.Value);
            end
        end

        % Value changed function: ReferenceCheckBox_3
        function ReferenceCheckBox_3ValueChanged(app, event)
            if app.ReferenceCheckBox_3.Value==1
                app.ReferenceCheckBox_2.Value=not(app.ReferenceCheckBox_3.Value);
            end
        end

        % Value changed function: ReferenceCheckBox_7
        function ReferenceCheckBox_7ValueChanged(app, event)
            if app.ReferenceCheckBox_7.Value==1
                app.ReferenceCheckBox_8.Value = not(app.ReferenceCheckBox_7.Value);
            end
        end

        % Value changed function: ReferenceCheckBox_8
        function ReferenceCheckBox_8ValueChanged(app, event)
            if app.ReferenceCheckBox_8.Value==1
                app.ReferenceCheckBox_7.Value = not(app.ReferenceCheckBox_8.Value);
            end
        end

        % Button pushed function: PlotEstimatesTrue
        function PlotEstimatesTruePushed(app, event)
            app.GraphsLabel.Value = 'Molality Estimates (red) + Measured Molality Values (blue/green)';
            app.GraphsLabel.FontSize = 22;
            
            met_names = {'GABA','tNAA','NAA','tCr','Cho','Ins','Glx','Glu','GSH','Tau'};
            Nmets = 10;
            
            %load normative data
            load(fullfile('InfantData','App_data.mat'));
            Nsubs = size(ald.metw,1);
            
            %assign metabolite signal values:
            if strcmp(app.Switch_2.Value, 'Database')
                gind = find(~isnan(mean(ald.metw,2))); %index of complete data in database
                subind=gind(app.UseDatabase.Value); %identify index of selected subject in the data
                sgn = ald.metw(subind,:); %assign signal values
                PMA = ald.PMA(subind);
            end
            if strcmp(app.Switch_2.Value, 'User-provided data')
                sgn = [app.GABAEditField.Value app.tNAAEditField.Value app.NAAEditField.Value app.tCrEditField.Value app.ChoEditField.Value app.InsEditField.Value app.GlxEditField.Value app.GluEditField.Value app.GSHEditField.Value app.TauEditField.Value];
                PMA = app.PMAEditField.Value;
            end
            
            %identify reference metabolites:
            my_refs = [app.ReferenceCheckBox.Value app.ReferenceCheckBox_2.Value app.ReferenceCheckBox_3.Value app.ReferenceCheckBox_4.Value app.ReferenceCheckBox_5.Value app.ReferenceCheckBox_6.Value app.ReferenceCheckBox_7.Value app.ReferenceCheckBox_8.Value app.ReferenceCheckBox_9.Value app.ReferenceCheckBox_10.Value];
    
%             %identify options selections
%             if app.UsewfactorsCheckBox.Value == 1
%                 is_weighting = 'y';
%             else
%                 is_weighting = 'n';
%             end
%             if app.AllowselfreferencingCheckBox.Value == 1
%                 is_self = 'y';
%             else
%                 is_self = 'n';
%             end
            is_weighting = 'y';
            is_self = 'n';
            
            mat_refs = NaN*ones(Nmets,Nmets);
            for jj = 1:Nmets
                %calculat a metabolite-specific mask
                mat_refs(jj,:) = my_refs;
                if strcmp(is_self,'n') %no self referencing
                    mat_refs(jj,jj) = 0; %no self-referencing
                    if strcmp(met_names{jj},'Glu') %we don't want to use Glx to reference Glu
                        mat_refs(jj,strcmp(met_names,'Glx')) = 0;
                    end
                    if strcmp(met_names{jj},'NAA') %we don't want to use tNAA to reference NAA
                        mat_refs(jj,strcmp(met_names,'tNAA')) = 0;
                    end
                    if strcmp(met_names{jj},'Glx') %we don't want to use Glx to reference Glu
                        mat_refs(jj,strcmp(met_names,'Glu')) = 0;
                    end
                    if strcmp(met_names{jj},'tNAA') %we don't want to use tNAA to reference NAA
                        mat_refs(jj,strcmp(met_names,'NAA')) = 0;
                    end
                elseif strcmp(is_self,'y') %allow self referencing, nothing to be done
                else
                    error('allow self referencing? y or n')
                end
            end
            
            % %calculate weighting factors:
            metw_wn = NaN*ones(Nmets,Nmets);
            allmets = ones(1,Nmets);
            for jj = 1:Nmets
                msk = logical(mat_refs(jj,:));
                har_mean = 1/mean(1./ald.metwSD(msk));
                if strcmp(is_weighting,'y') %if weighiting factors are to be used:
                    metw_wn(jj,msk) = har_mean./ald.metwSD(msk)/nnz(msk);
                elseif strcmp(is_weighting,'n')
                    metw_wn(jj,msk) = allmets(msk);
                else
                    error('use weighting factors? y or n')
                end
            end
            
            %For the single subject, calculate expected concentration values using
            %polynomial constants obtained from the normative data:
            for jj = 1:Nmets
                C_E(jj) = polyval(ald.metfw_ps(jj,:),PMA,[],ald.mu)';
            end
            
            %for the single subject, calculate R_CRef and C_CRef:
            for jj = 1:Nmets
                msk = logical(mat_refs(jj,:));  %identify reference metabolties
                %R_CReF:
                R_CRef(jj) = sgn(jj)/sum(metw_wn(jj,msk).*sgn(msk));
                %C_CRef:
                C_CRef(jj) = R_CRef(jj).*sum(metw_wn(jj,msk).*C_E(msk));
            end
            
            %For the normative data, calculate R_CRef and C_CRef
            for kk = 1:Nsubs
                for jj = 1:Nmets
                    msk = logical(mat_refs(jj,:));
                    %R_CReF:
                    all_R_CRef(kk,jj) = ald.metw(kk,jj)/sum(metw_wn(jj,msk).*ald.metw(kk,msk));
                    %C_CRef:
                    all_C_CRef(kk,jj) = all_R_CRef(kk,jj).*sum(metw_wn(jj,msk).*ald.metfwE(kk,msk));
                end
            end
            
            %             %for normative data, calcuate average PMA dependence:
            for jj = 1:Nmets
                [r,~,~] = find(~isnan(all_R_CRef(:,jj))); %identify NaNs
                %R_CRef
                [ps] = polyfit((ald.PMA(r)-ald.mu(1))/ald.mu(2),all_R_CRef(r,jj),2);
                all_R_CRef_ps(jj,:) = ps;
                all_R_CRef_E(:,jj) = polyval(ps,ald.PMA,[],ald.mu); %expected values for normatived data.
                R_CRef_E(jj) = polyval(ps,PMA,[],ald.mu); %expected value for the single subject.
                
                %C_CRef
                [ps] = polyfit((ald.PMA(r)-ald.mu(1))/ald.mu(2),all_C_CRef(r,jj),2);
                all_C_CRef_ps(jj,:) = ps;
                all_C_CRef_E(:,jj) = polyval(ps,ald.PMA,[],ald.mu); %expected values for normatived data.
                C_CRef_E(jj) = polyval(ps,PMA,[],ald.mu); %expected value for the single subject.
            end
            
            %for normative data, calculate SD values of C_CRef and R_CRef
            for jj = 1:Nmets
                [r,~,~] = find(~isnan(all_R_CRef(:,jj))); %identify NaNs
                R_CRef_SD(jj) = std(all_R_CRef(r,jj) - all_R_CRef_E(r,jj));
                C_CRef_SD(jj) = std(all_C_CRef(r,jj) - all_C_CRef_E(r,jj));
            end
            
            %for normative data, calculate error estimates via comparison of estimated concentrations of normative data with "ground truth" :corrected-water scaled values from a subset of infants
            for jj = 1:Nmets
                C_Cref_error(jj) =nanstd(all_C_CRef(:,jj) - ald.metfw(:,jj));
            end
            
            %calculate percentiles of the single subject, based on group statistics.
            for jj = 1:Nmets
                pct_R_CRef(jj) = round(100*normcdf(R_CRef(jj),R_CRef_E(jj),R_CRef_SD(jj)),0);
                pct_C_CRef(jj) = round(100*normcdf(C_CRef(jj),C_CRef_E(jj),C_CRef_SD(jj)),0);
            end
            
            %calculate percentils of the true values:
            for jj = 1:Nmets
                pct_C_WSc(jj) = round(100*normcdf(ald.metfw(subind,jj),ald.metfwE(subind,jj),ald.metfwSD(jj),0));
            end
            
            %Plot the results:
            xs = 260:1:440;% PMA range for continuous plots
            
            % Plot GABA
            jj=1;
            cla(app.PrincipalInterestUIAxes);
            if app.GABAEditField.Value~=0
                
                %plot individuals from the normative data
                plot(app.PrincipalInterestUIAxes,ald.PMA*12/365.25,all_C_CRef(:,jj),'ks','markersize',3)
                set(app.PrincipalInterestUIAxes,'YMinorTick','on')
                set(app.PrincipalInterestUIAxes,'XMinorTick','on')
                hold(app.PrincipalInterestUIAxes,'on')
                errorbar(app.PrincipalInterestUIAxes,PMA*12/365.25,C_CRef(jj),C_Cref_error(jj),'ko','markerfacecolor','r','markersize',9,'linewidth',2)
                
                %plot the curve fits
                ys= polyval(all_C_CRef_ps(jj,:),xs,[],ald.mu);
                plot(app.PrincipalInterestUIAxes,xs*12/365.25,ys,'k-','linewidth',2);
                plot(app.PrincipalInterestUIAxes,xs*12/365.25,ys+C_CRef_SD(jj),'k--','linewidth',2);
                plot(app.PrincipalInterestUIAxes,xs*12/365.25,ys-C_CRef_SD(jj),'k--','linewidth',2);


                %     %option: plot true concentrations
                %average behavior:
                ys = polyval(ald.metfw_ps(jj,:),xs,[],ald.mu)';
                plot(app.PrincipalInterestUIAxes,xs*12/365.25,ys,'b--','linewidth',1.5);
                %all subjects:
                %plot(app.PrincipalInterestUIAxes,ald.PMA*12/365.25,ald.metfw(:,jj),'bd','markersize',3)
                %subject of interest:
                plot(app.PrincipalInterestUIAxes,PMA*12/365.25,ald.metfw(subind,jj),'bd','markerfacecolor','g','markersize',7,'linewidth',2)
                
                
                set(app.PrincipalInterestUIAxes,'TickLength',[0.03 0.03])
                set(app.PrincipalInterestUIAxes,'Fontsize',11,'linewidth',1.2)
                
                title(app.PrincipalInterestUIAxes,{met_names{jj};strcat('Molality Estimate percentile: ', num2str(pct_C_CRef(jj)),'%');strcat('Molality percentile: ', num2str(pct_C_WSc(jj)),'%')},'fontsize',11)
                ylabel(app.PrincipalInterestUIAxes,strcat(met_names{jj},' (mmol/kg)'),'fontsize',11)
                xlabel(app.PrincipalInterestUIAxes,'PMA (months)','fontsize',11)
                xlim(app.PrincipalInterestUIAxes,[260 440]*12/365.25)
                
                set(app.PrincipalInterestUIAxes,'color','w');
                set(app.PrincipalInterestUIAxes,'Fontsize',11,'linewidth',2)
            end
            % Plot tNAA
            jj=2;
            cla(app.PrincipalInterestUIAxes_2);
            if app.tNAAEditField.Value~=0
                %plot individuals from the normative data
                plot(app.PrincipalInterestUIAxes_2,ald.PMA*12/365.25,all_C_CRef(:,jj),'ks','markersize',3)                
                set(app.PrincipalInterestUIAxes_2,'YMinorTick','on')
                set(app.PrincipalInterestUIAxes_2,'XMinorTick','on')
                hold(app.PrincipalInterestUIAxes_2,'on')
                errorbar(app.PrincipalInterestUIAxes_2,PMA*12/365.25,C_CRef(jj),C_Cref_error(jj),'ko','markerfacecolor','r','markersize',9,'linewidth',2)
                
                %plot the curve fits
                ys= polyval(all_C_CRef_ps(jj,:),xs,[],ald.mu);
                plot(app.PrincipalInterestUIAxes_2,xs*12/365.25,ys,'k-','linewidth',2);
                plot(app.PrincipalInterestUIAxes_2,xs*12/365.25,ys+C_CRef_SD(jj),'k--','linewidth',2);
                plot(app.PrincipalInterestUIAxes_2,xs*12/365.25,ys-C_CRef_SD(jj),'k--','linewidth',2);


                %     %option: plot true concentrations
                %average behavior:
                ys = polyval(ald.metfw_ps(jj,:),xs,[],ald.mu)';
                plot(app.PrincipalInterestUIAxes_2,xs*12/365.25,ys,'b--','linewidth',1.5);
                %all subjects:
                %plot(app.PrincipalInterestUIAxes_2,ald.PMA*12/365.25,ald.metfw(:,jj),'bd','markersize',3)
                %subject of interest:
                plot(app.PrincipalInterestUIAxes_2,PMA*12/365.25,ald.metfw(subind,jj),'bd','markerfacecolor','g','markersize',7,'linewidth',2)
                
                
                set(app.PrincipalInterestUIAxes_2,'TickLength',[0.03 0.03])
                set(app.PrincipalInterestUIAxes_2,'Fontsize',11,'linewidth',1.2)
                
                title(app.PrincipalInterestUIAxes_2,{met_names{jj};strcat('Molality Estimate percentile: ', num2str(pct_C_CRef(jj)),'%');strcat('Molality percentile: ', num2str(pct_C_WSc(jj)),'%')},'fontsize',11);
                ylabel(app.PrincipalInterestUIAxes_2,strcat(met_names{jj},' (mmol/kg)'),'fontsize',11)
                xlabel(app.PrincipalInterestUIAxes_2,'PMA (months)','fontsize',11)
                xlim(app.PrincipalInterestUIAxes_2,[260 440]*12/365.25)
                
                set(app.PrincipalInterestUIAxes_2,'color','w');
                set(app.PrincipalInterestUIAxes_2,'Fontsize',11,'linewidth',2)
            end
            % Plot NAA
            jj=3;
            cla(app.PrincipalInterestUIAxes_3);
            if app.NAAEditField.Value~=0
                
                %plot individuals from the normative data
                plot(app.PrincipalInterestUIAxes_3,ald.PMA*12/365.25,all_C_CRef(:,jj),'ks','markersize',3)
                set(app.PrincipalInterestUIAxes_3,'YMinorTick','on')
                set(app.PrincipalInterestUIAxes_3,'XMinorTick','on')
                hold(app.PrincipalInterestUIAxes_3,'on')
                errorbar(app.PrincipalInterestUIAxes_3,PMA*12/365.25,C_CRef(jj),C_Cref_error(jj),'ko','markerfacecolor','r','markersize',9,'linewidth',2)
                
                %plot the curve fits
                ys= polyval(all_C_CRef_ps(jj,:),xs,[],ald.mu);
                plot(app.PrincipalInterestUIAxes_3,xs*12/365.25,ys,'k-','linewidth',2);
                plot(app.PrincipalInterestUIAxes_3,xs*12/365.25,ys+C_CRef_SD(jj),'k--','linewidth',2);
                plot(app.PrincipalInterestUIAxes_3,xs*12/365.25,ys-C_CRef_SD(jj),'k--','linewidth',2);
                
                %     %option: plot true concentrations
                %average behavior:
                ys = polyval(ald.metfw_ps(jj,:),xs,[],ald.mu)';
                plot(app.PrincipalInterestUIAxes_3,xs*12/365.25,ys,'b--','linewidth',1.5);
                %all subjects:
                %plot(app.PrincipalInterestUIAxes_3,ald.PMA*12/365.25,ald.metfw(:,jj),'bd','markersize',3)
                %subject of interest:
                plot(app.PrincipalInterestUIAxes_3,PMA*12/365.25,ald.metfw(subind,jj),'bd','markerfacecolor','g','markersize',7,'linewidth',2)
                
                
                set(app.PrincipalInterestUIAxes_3,'TickLength',[0.03 0.03])
                set(app.PrincipalInterestUIAxes_3,'Fontsize',11,'linewidth',1.2)
                
                title(app.PrincipalInterestUIAxes_3,{met_names{jj};strcat('Molality Estimate percentile: ', num2str(pct_C_CRef(jj)),'%');strcat('Molality percentile: ', num2str(pct_C_WSc(jj)),'%')},'fontsize',11);
                ylabel(app.PrincipalInterestUIAxes_3,strcat(met_names{jj},' (mmol/kg)'),'fontsize',11)
                xlabel(app.PrincipalInterestUIAxes_3,'PMA (months)','fontsize',11)
                xlim(app.PrincipalInterestUIAxes_3,[260 440]*12/365.25)
                
                set(app.PrincipalInterestUIAxes_3,'color','w');
                set(app.PrincipalInterestUIAxes_3,'Fontsize',11,'linewidth',2)
            end
            % Plot tCr
            jj=4;
            cla(app.PrincipalInterestUIAxes_4);
            if app.tCrEditField.Value~=0
                %plot individuals from the normative data
                plot(app.PrincipalInterestUIAxes_4,ald.PMA*12/365.25,all_C_CRef(:,jj),'ks','markersize',3)                
                set(app.PrincipalInterestUIAxes_4,'YMinorTick','on')
                set(app.PrincipalInterestUIAxes_4,'XMinorTick','on')
                hold(app.PrincipalInterestUIAxes_4,'on')
                errorbar(app.PrincipalInterestUIAxes_4,PMA*12/365.25,C_CRef(jj),C_Cref_error(jj),'ko','markerfacecolor','r','markersize',9,'linewidth',2)
                
                %plot the curve fits
                ys= polyval(all_C_CRef_ps(jj,:),xs,[],ald.mu);
                plot(app.PrincipalInterestUIAxes_4,xs*12/365.25,ys,'k-','linewidth',2);
                plot(app.PrincipalInterestUIAxes_4,xs*12/365.25,ys+C_CRef_SD(jj),'k--','linewidth',2);
                plot(app.PrincipalInterestUIAxes_4,xs*12/365.25,ys-C_CRef_SD(jj),'k--','linewidth',2);


                %     %option: plot true concentrations
                %average behavior:
                ys = polyval(ald.metfw_ps(jj,:),xs,[],ald.mu)';
                plot(app.PrincipalInterestUIAxes_4,xs*12/365.25,ys,'b--','linewidth',1.5);
                %all subjects:
                %plot(app.PrincipalInterestUIAxes_4,ald.PMA*12/365.25,ald.metfw(:,jj),'bd','markersize',3)
                %subject of interest:
                plot(app.PrincipalInterestUIAxes_4,PMA*12/365.25,ald.metfw(subind,jj),'bd','markerfacecolor','g','markersize',7,'linewidth',2)
                
                
                set(app.PrincipalInterestUIAxes_4,'TickLength',[0.03 0.03])
                set(app.PrincipalInterestUIAxes_4,'Fontsize',11,'linewidth',1.2)
                
                title(app.PrincipalInterestUIAxes_4,{met_names{jj};strcat('Molality Estimate percentile: ', num2str(pct_C_CRef(jj)),'%');strcat('Molality percentile: ', num2str(pct_C_WSc(jj)),'%')},'fontsize',11);
                ylabel(app.PrincipalInterestUIAxes_4,strcat(met_names{jj},' (mmol/kg)'),'fontsize',11)
                xlabel(app.PrincipalInterestUIAxes_4,'PMA (months)','fontsize',11)
                xlim(app.PrincipalInterestUIAxes_4,[260 440]*12/365.25)
                
                set(app.PrincipalInterestUIAxes_4,'color','w');
                set(app.PrincipalInterestUIAxes_4,'Fontsize',11,'linewidth',2)
            end
            % Plot Cho
            jj=5;
            cla(app.PrincipalInterestUIAxes_5);
            if app.ChoEditField.Value~=0
                %plot individuals from the normative data
                plot(app.PrincipalInterestUIAxes_5,ald.PMA*12/365.25,all_C_CRef(:,jj),'ks','markersize',3)
                
                set(app.PrincipalInterestUIAxes_5,'YMinorTick','on')
                set(app.PrincipalInterestUIAxes_5,'XMinorTick','on')
                hold(app.PrincipalInterestUIAxes_5,'on')
                errorbar(app.PrincipalInterestUIAxes_5,PMA*12/365.25,C_CRef(jj),C_Cref_error(jj),'ko','markerfacecolor','r','markersize',9,'linewidth',2)
                
                %plot the curve fits
                ys= polyval(all_C_CRef_ps(jj,:),xs,[],ald.mu);
                plot(app.PrincipalInterestUIAxes_5,xs*12/365.25,ys,'k-','linewidth',2);
                plot(app.PrincipalInterestUIAxes_5,xs*12/365.25,ys+C_CRef_SD(jj),'k--','linewidth',2);
                plot(app.PrincipalInterestUIAxes_5,xs*12/365.25,ys-C_CRef_SD(jj),'k--','linewidth',2);


                %     %option: plot true concentrations
                %average behavior:
                ys = polyval(ald.metfw_ps(jj,:),xs,[],ald.mu)';
                plot(app.PrincipalInterestUIAxes_5,xs*12/365.25,ys,'b--','linewidth',1.5);
                %all subjects:
                %plot(app.PrincipalInterestUIAxes_5,ald.PMA*12/365.25,ald.metfw(:,jj),'bd','markersize',3)
                %subject of interest:
                plot(app.PrincipalInterestUIAxes_5,PMA*12/365.25,ald.metfw(subind,jj),'bd','markerfacecolor','g','markersize',7,'linewidth',2)
                
                
                set(app.PrincipalInterestUIAxes_5,'TickLength',[0.03 0.03])
                set(app.PrincipalInterestUIAxes_5,'Fontsize',11,'linewidth',1.2)
                
                title(app.PrincipalInterestUIAxes_5,{met_names{jj};strcat('Molality Estimate percentile: ', num2str(pct_C_CRef(jj)),'%');strcat('Molality percentile: ', num2str(pct_C_WSc(jj)),'%')},'fontsize',11);
                ylabel(app.PrincipalInterestUIAxes_5,strcat(met_names{jj},' (mmol/kg)'),'fontsize',11)
                xlabel(app.PrincipalInterestUIAxes_5,'PMA (months)','fontsize',11)
                xlim(app.PrincipalInterestUIAxes_5,[260 440]*12/365.25)
                
                set(app.PrincipalInterestUIAxes_5,'color','w');
                set(app.PrincipalInterestUIAxes_5,'Fontsize',11,'linewidth',2)
            end
            jj=6;
            cla(app.PrincipalInterestUIAxes_6);
            if app.InsEditField.Value~=0
                %plot individuals from the normative data
                plot(app.PrincipalInterestUIAxes_6,ald.PMA*12/365.25,all_C_CRef(:,jj),'ks','markersize',3)
                set(app.PrincipalInterestUIAxes_6,'YMinorTick','on')
                set(app.PrincipalInterestUIAxes_6,'XMinorTick','on')
                hold(app.PrincipalInterestUIAxes_6,'on')
                errorbar(app.PrincipalInterestUIAxes_6,PMA*12/365.25,C_CRef(jj),C_Cref_error(jj),'ko','markerfacecolor','r','markersize',9,'linewidth',2)
                
                %plot the curve fits
                ys= polyval(all_C_CRef_ps(jj,:),xs,[],ald.mu);
                plot(app.PrincipalInterestUIAxes_6,xs*12/365.25,ys,'k-','linewidth',2);
                plot(app.PrincipalInterestUIAxes_6,xs*12/365.25,ys+C_CRef_SD(jj),'k--','linewidth',2);
                plot(app.PrincipalInterestUIAxes_6,xs*12/365.25,ys-C_CRef_SD(jj),'k--','linewidth',2);


                %     %option: plot true concentrations
                %average behavior:
                ys = polyval(ald.metfw_ps(jj,:),xs,[],ald.mu)';
                plot(app.PrincipalInterestUIAxes_6,xs*12/365.25,ys,'b--','linewidth',1.5);
                %all subjects:
                %plot(app.PrincipalInterestUIAxes_6,ald.PMA*12/365.25,ald.metfw(:,jj),'bd','markersize',3)
                %subject of interest:
                plot(app.PrincipalInterestUIAxes_6,PMA*12/365.25,ald.metfw(subind,jj),'bd','markerfacecolor','g','markersize',7,'linewidth',2)
                
                
                set(app.PrincipalInterestUIAxes_6,'TickLength',[0.03 0.03])
                set(app.PrincipalInterestUIAxes_6,'Fontsize',11,'linewidth',1.2)
                
                title(app.PrincipalInterestUIAxes_6,{met_names{jj};strcat('Molality Estimate percentile: ', num2str(pct_C_CRef(jj)),'%');strcat('Molality percentile: ', num2str(pct_C_WSc(jj)),'%')},'fontsize',11);
                ylabel(app.PrincipalInterestUIAxes_6,strcat(met_names{jj},' (mmol/kg)'),'fontsize',11)
                xlabel(app.PrincipalInterestUIAxes_6,'PMA (months)','fontsize',11)
                xlim(app.PrincipalInterestUIAxes_6,[260 440]*12/365.25)
                
                set(app.PrincipalInterestUIAxes_6,'color','w');
                set(app.PrincipalInterestUIAxes_6,'Fontsize',11,'linewidth',2)
            end
            jj=7;
            cla(app.PrincipalInterestUIAxes_7);
            if app.GlxEditField.Value~=0
                %plot individuals from the normative data
                plot(app.PrincipalInterestUIAxes_7,ald.PMA*12/365.25,all_C_CRef(:,jj),'ks','markersize',3)                
                set(app.PrincipalInterestUIAxes_7,'YMinorTick','on')
                set(app.PrincipalInterestUIAxes_7,'XMinorTick','on')
                hold(app.PrincipalInterestUIAxes_7,'on')
                errorbar(app.PrincipalInterestUIAxes_7,PMA*12/365.25,C_CRef(jj),C_Cref_error(jj),'ko','markerfacecolor','r','markersize',9,'linewidth',2)
                
                %plot the curve fits
                ys= polyval(all_C_CRef_ps(jj,:),xs,[],ald.mu);
                plot(app.PrincipalInterestUIAxes_7,xs*12/365.25,ys,'k-','linewidth',2);
                plot(app.PrincipalInterestUIAxes_7,xs*12/365.25,ys+C_CRef_SD(jj),'k--','linewidth',2);
                plot(app.PrincipalInterestUIAxes_7,xs*12/365.25,ys-C_CRef_SD(jj),'k--','linewidth',2);


                %     %option: plot true concentrations
                %average behavior:
                ys = polyval(ald.metfw_ps(jj,:),xs,[],ald.mu)';
                plot(app.PrincipalInterestUIAxes_7,xs*12/365.25,ys,'b--','linewidth',1.5);
                %all subjects:
                %plot(app.PrincipalInterestUIAxes_7,ald.PMA*12/365.25,ald.metfw(:,jj),'bd','markersize',3)
                %subject of interest:
                plot(app.PrincipalInterestUIAxes_7,PMA*12/365.25,ald.metfw(subind,jj),'bd','markerfacecolor','g','markersize',7,'linewidth',2)
                
                
                set(app.PrincipalInterestUIAxes_7,'TickLength',[0.03 0.03])
                set(app.PrincipalInterestUIAxes_7,'Fontsize',11,'linewidth',1.2)
                
                title(app.PrincipalInterestUIAxes_7,{met_names{jj};strcat('Molality Estimate percentile: ', num2str(pct_C_CRef(jj)),'%');strcat('Molality percentile: ', num2str(pct_C_WSc(jj)),'%')},'fontsize',11);
                ylabel(app.PrincipalInterestUIAxes_7,strcat(met_names{jj},' (mmol/kg)'),'fontsize',11)
                xlabel(app.PrincipalInterestUIAxes_7,'PMA (months)','fontsize',11)
                xlim(app.PrincipalInterestUIAxes_7,[260 440]*12/365.25)
                
                set(app.PrincipalInterestUIAxes_7,'color','w');
                set(app.PrincipalInterestUIAxes_7,'Fontsize',11,'linewidth',2)
            end
            jj=8;
            cla(app.PrincipalInterestUIAxes_8);
            if app.GluEditField.Value~=0
                
                %plot individuals from the normative data
                plot(app.PrincipalInterestUIAxes_8,ald.PMA*12/365.25,all_C_CRef(:,jj),'ks','markersize',3)
                set(app.PrincipalInterestUIAxes_8,'YMinorTick','on')
                set(app.PrincipalInterestUIAxes_8,'XMinorTick','on')
                hold(app.PrincipalInterestUIAxes_8,'on')
                errorbar(app.PrincipalInterestUIAxes_8,PMA*12/365.25,C_CRef(jj),C_Cref_error(jj),'ko','markerfacecolor','r','markersize',9,'linewidth',2)
                
                %plot the curve fits
                ys= polyval(all_C_CRef_ps(jj,:),xs,[],ald.mu);
                plot(app.PrincipalInterestUIAxes_8,xs*12/365.25,ys,'k-','linewidth',2);
                plot(app.PrincipalInterestUIAxes_8,xs*12/365.25,ys+C_CRef_SD(jj),'k--','linewidth',2);
                plot(app.PrincipalInterestUIAxes_8,xs*12/365.25,ys-C_CRef_SD(jj),'k--','linewidth',2);


                %     %option: plot true concentrations
                %average behavior:
                ys = polyval(ald.metfw_ps(jj,:),xs,[],ald.mu)';
                plot(app.PrincipalInterestUIAxes_8,xs*12/365.25,ys,'b--','linewidth',1.5);
                %all subjects:
                %plot(app.PrincipalInterestUIAxes_8,ald.PMA*12/365.25,ald.metfw(:,jj),'bd','markersize',3)
                %subject of interest:
                plot(app.PrincipalInterestUIAxes_8,PMA*12/365.25,ald.metfw(subind,jj),'bd','markerfacecolor','g','markersize',7,'linewidth',2)
                
                
                set(app.PrincipalInterestUIAxes_8,'TickLength',[0.03 0.03])
                set(app.PrincipalInterestUIAxes_8,'Fontsize',11,'linewidth',1.2)
                
                title(app.PrincipalInterestUIAxes_8,{met_names{jj};strcat('Molality Estimate percentile: ', num2str(pct_C_CRef(jj)),'%');strcat('Molality percentile: ', num2str(pct_C_WSc(jj)),'%')},'fontsize',11);
                ylabel(app.PrincipalInterestUIAxes_8,strcat(met_names{jj},' (mmol/kg)'),'fontsize',11)
                xlabel(app.PrincipalInterestUIAxes_8,'PMA (months)','fontsize',11)
                xlim(app.PrincipalInterestUIAxes_8,[260 440]*12/365.25)
                
                set(app.PrincipalInterestUIAxes_8,'color','w');
                set(app.PrincipalInterestUIAxes_8,'Fontsize',11,'linewidth',2)
            end
            jj=9;
            cla(app.PrincipalInterestUIAxes_9);
            if app.GSHEditField.Value~=0
                %plot individuals from the normative data
                plot(app.PrincipalInterestUIAxes_9,ald.PMA*12/365.25,all_C_CRef(:,jj),'ks','markersize',3)                
                set(app.PrincipalInterestUIAxes_9,'YMinorTick','on')
                set(app.PrincipalInterestUIAxes_9,'XMinorTick','on')
                hold(app.PrincipalInterestUIAxes_9,'on')
                errorbar(app.PrincipalInterestUIAxes_9,PMA*12/365.25,C_CRef(jj),C_Cref_error(jj),'ko','markerfacecolor','r','markersize',9,'linewidth',2)
                
                %plot the curve fits
                ys= polyval(all_C_CRef_ps(jj,:),xs,[],ald.mu);
                plot(app.PrincipalInterestUIAxes_9,xs*12/365.25,ys,'k-','linewidth',2);
                plot(app.PrincipalInterestUIAxes_9,xs*12/365.25,ys+C_CRef_SD(jj),'k--','linewidth',2);
                plot(app.PrincipalInterestUIAxes_9,xs*12/365.25,ys-C_CRef_SD(jj),'k--','linewidth',2);


                %     %option: plot true concentrations
                %average behavior:
                ys = polyval(ald.metfw_ps(jj,:),xs,[],ald.mu)';
                plot(app.PrincipalInterestUIAxes_9,xs*12/365.25,ys,'b--','linewidth',1.5);
                %all subjects:
                %plot(app.PrincipalInterestUIAxes_9,ald.PMA*12/365.25,ald.metfw(:,jj),'bd','markersize',3)
                %subject of interest:
                plot(app.PrincipalInterestUIAxes_9,PMA*12/365.25,ald.metfw(subind,jj),'bd','markerfacecolor','g','markersize',7,'linewidth',2)
                
                
                set(app.PrincipalInterestUIAxes_9,'TickLength',[0.03 0.03])
                set(app.PrincipalInterestUIAxes_9,'Fontsize',11,'linewidth',1.2)
                
                title(app.PrincipalInterestUIAxes_9,{met_names{jj};strcat('Molality Estimate percentile: ', num2str(pct_C_CRef(jj)),'%');strcat('Molality percentile: ', num2str(pct_C_WSc(jj)),'%')},'fontsize',11);
                ylabel(app.PrincipalInterestUIAxes_9,strcat(met_names{jj},' (mmol/kg)'),'fontsize',11)
                xlabel(app.PrincipalInterestUIAxes_9,'PMA (months)','fontsize',11)
                xlim(app.PrincipalInterestUIAxes_9,[260 440]*12/365.25)
                
                set(app.PrincipalInterestUIAxes_9,'color','w');
                set(app.PrincipalInterestUIAxes_9,'Fontsize',11,'linewidth',2)
            end
            jj=10;
            cla(app.PrincipalInterestUIAxes_10);
            if app.TauEditField.Value~=0
                
                %plot individuals from the normative data
                plot(app.PrincipalInterestUIAxes_10,ald.PMA*12/365.25,all_C_CRef(:,jj),'ks','markersize',3)
                set(app.PrincipalInterestUIAxes_10,'YMinorTick','on')
                set(app.PrincipalInterestUIAxes_10,'XMinorTick','on')
                hold(app.PrincipalInterestUIAxes_10,'on')
                errorbar(app.PrincipalInterestUIAxes_10,PMA*12/365.25,C_CRef(jj),C_Cref_error(jj),'ko','markerfacecolor','r','markersize',9,'linewidth',2)
                
                %plot the curve fits
                ys= polyval(all_C_CRef_ps(jj,:),xs,[],ald.mu);
                plot(app.PrincipalInterestUIAxes_10,xs*12/365.25,ys,'k-','linewidth',2);
                plot(app.PrincipalInterestUIAxes_10,xs*12/365.25,ys+C_CRef_SD(jj),'k--','linewidth',2);
                plot(app.PrincipalInterestUIAxes_10,xs*12/365.25,ys-C_CRef_SD(jj),'k--','linewidth',2);


                %     %option: plot true concentrations
                %average behavior:
                ys = polyval(ald.metfw_ps(jj,:),xs,[],ald.mu)';
                plot(app.PrincipalInterestUIAxes_10,xs*12/365.25,ys,'b--','linewidth',1.5);
                %all subjects:
                %plot(app.PrincipalInterestUIAxes_10,ald.PMA*12/365.25,ald.metfw(:,jj),'bd','markersize',3)
                %subject of interest:
                plot(app.PrincipalInterestUIAxes_10,PMA*12/365.25,ald.metfw(subind,jj),'bd','markerfacecolor','g','markersize',7,'linewidth',2)
                
                
                set(app.PrincipalInterestUIAxes_10,'TickLength',[0.03 0.03])
                set(app.PrincipalInterestUIAxes_10,'Fontsize',11,'linewidth',1.2)
                
                title(app.PrincipalInterestUIAxes_10,{met_names{jj};strcat('Molality Estimate percentile: ', num2str(pct_C_CRef(jj)),'%');strcat('Molality percentile: ', num2str(pct_C_WSc(jj)),'%')},'fontsize',11);
                ylabel(app.PrincipalInterestUIAxes_10,strcat(met_names{jj},' (mmol/kg)'),'fontsize',11)
                xlabel(app.PrincipalInterestUIAxes_10,'PMA (months)','fontsize',11)
                xlim(app.PrincipalInterestUIAxes_10,[260 440]*12/365.25)
                
                set(app.PrincipalInterestUIAxes_10,'color','w');
                set(app.PrincipalInterestUIAxes_10,'Fontsize',11,'linewidth',2)
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.InfantMRSUIFigure = uifigure('Visible', 'off');
            app.InfantMRSUIFigure.Name = 'Infant MRS';
            app.InfantMRSUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.InfantMRSUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.PlotMolalityEstimatesButton = uibutton(app.LeftPanel, 'push');
            app.PlotMolalityEstimatesButton.ButtonPushedFcn = createCallbackFcn(app, @PlotMolalityEstimatesButtonPushed, true);
            app.PlotMolalityEstimatesButton.Text = 'Plot Molality Estimates';
            app.ReferenceCheckBox = uicheckbox(app.LeftPanel);
            app.ReferenceCheckBox.Text = 'Reference';
            app.ReferenceCheckBox.Value = true;
            app.ReferenceCheckBox_2 = uicheckbox(app.LeftPanel);
            app.ReferenceCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @ReferenceCheckBox_2ValueChanged, true);
            app.ReferenceCheckBox_2.Text = 'Reference';
            app.ReferenceCheckBox_2.Value = true;
            app.ReferenceCheckBox_3 = uicheckbox(app.LeftPanel);
            app.ReferenceCheckBox_3.ValueChangedFcn = createCallbackFcn(app, @ReferenceCheckBox_3ValueChanged, true);
            app.ReferenceCheckBox_3.Text = 'Reference';
            app.ReferenceCheckBox_4 = uicheckbox(app.LeftPanel);
            app.ReferenceCheckBox_4.Text = 'Reference';
            app.ReferenceCheckBox_4.Value = true;
            app.ReferenceCheckBox_5 = uicheckbox(app.LeftPanel);
            app.ReferenceCheckBox_5.Text = 'Reference';
            app.ReferenceCheckBox_5.Value = true;
            app.ReferenceCheckBox_6 = uicheckbox(app.LeftPanel);
            app.ReferenceCheckBox_6.Text = 'Reference';
            app.ReferenceCheckBox_6.Value = true;
            app.ReferenceCheckBox_7 = uicheckbox(app.LeftPanel);
            app.ReferenceCheckBox_7.ValueChangedFcn = createCallbackFcn(app, @ReferenceCheckBox_7ValueChanged, true);
            app.ReferenceCheckBox_7.Text = 'Reference';
            app.ReferenceCheckBox_7.Value = true;
            app.ReferenceCheckBox_8 = uicheckbox(app.LeftPanel);
            app.ReferenceCheckBox_8.ValueChangedFcn = createCallbackFcn(app, @ReferenceCheckBox_8ValueChanged, true);
            app.ReferenceCheckBox_8.Text = 'Reference';
            app.ReferenceCheckBox_9 = uicheckbox(app.LeftPanel);
            app.ReferenceCheckBox_9.Text = 'Reference';
            app.ReferenceCheckBox_9.Value = true;
            app.ReferenceCheckBox_10 = uicheckbox(app.LeftPanel);
            app.ReferenceCheckBox_10.Text = 'Reference';
            app.GABAEditFieldLabel = uilabel(app.LeftPanel);
            app.GABAEditFieldLabel.Text = 'GABA';
            app.GABAEditField = uieditfield(app.LeftPanel, 'numeric');
            app.GABAEditField.Limits = [0 10000000];
            app.GABAEditField.ValueDisplayFormat = '%8.3f';
            app.GABAEditField.Editable = 'off';
            app.GABAEditField.Value = 0.7455;
            app.tNAAEditFieldLabel = uilabel(app.LeftPanel);
            app.tNAAEditFieldLabel.Text = 'tNAA';
            app.tNAAEditField = uieditfield(app.LeftPanel, 'numeric');
            app.tNAAEditField.Limits = [0 10000000];
            app.tNAAEditField.ValueDisplayFormat = '%8.3f';
            app.tNAAEditField.Editable = 'off';
            app.tNAAEditField.Value = 2.841;
            app.NAAEditFieldLabel = uilabel(app.LeftPanel);
            app.NAAEditFieldLabel.Text = 'NAA';
            app.NAAEditField = uieditfield(app.LeftPanel, 'numeric');
            app.NAAEditField.Limits = [0 10000000];
            app.NAAEditField.ValueDisplayFormat = '%8.3f';
            app.NAAEditField.Editable = 'off';
            app.NAAEditField.Value = 2.25;
            app.tCrEditFieldLabel = uilabel(app.LeftPanel);
            app.tCrEditFieldLabel.Text = 'tCr';
            app.tCrEditField = uieditfield(app.LeftPanel, 'numeric');
            app.tCrEditField.Limits = [0 10000000];
            app.tCrEditField.ValueDisplayFormat = '%8.3f';
            app.tCrEditField.Editable = 'off';
            app.tCrEditField.Value = 2.4;
            app.ChoEditFieldLabel = uilabel(app.LeftPanel);
            app.ChoEditFieldLabel.Text = 'Cho';
            app.ChoEditField = uieditfield(app.LeftPanel, 'numeric');
            app.ChoEditField.Limits = [0 10000000];
            app.ChoEditField.ValueDisplayFormat = '%8.3f';
            app.ChoEditField.Editable = 'off';
            app.ChoEditField.Value = 1.08;
            app.InsEditFieldLabel = uilabel(app.LeftPanel);
            app.InsEditFieldLabel.Text = 'Ins';
            app.InsEditField = uieditfield(app.LeftPanel, 'numeric');
            app.InsEditField.Limits = [0 10000000];
            app.InsEditField.ValueDisplayFormat = '%8.3f';
            app.InsEditField.Editable = 'off';
            app.InsEditField.Value = 3.573;
            app.GlxEditFieldLabel = uilabel(app.LeftPanel);
            app.GlxEditFieldLabel.Text = 'Glx';
            app.GlxEditField = uieditfield(app.LeftPanel, 'numeric');
            app.GlxEditField.Limits = [0 10000000];
            app.GlxEditField.ValueDisplayFormat = '%8.3f';
            app.GlxEditField.Editable = 'off';
            app.GlxEditField.Value = 2.158;
            app.GluEditFieldLabel = uilabel(app.LeftPanel);
            app.GluEditFieldLabel.Text = 'Glu';
            app.GluEditField = uieditfield(app.LeftPanel, 'numeric');
            app.GluEditField.Limits = [0 10000000];
            app.GluEditField.ValueDisplayFormat = '%8.3f';
            app.GluEditField.Editable = 'off';
            app.GluEditField.Value = 1.759;
            app.GSHEditFieldLabel = uilabel(app.LeftPanel);
            app.GSHEditFieldLabel.Text = 'GSH';
            app.GSHEditField = uieditfield(app.LeftPanel, 'numeric');
            app.GSHEditField.Limits = [0 10000000];
            app.GSHEditField.ValueDisplayFormat = '%8.3f';
            app.GSHEditField.Editable = 'off';
            app.GSHEditField.Value = 0.865;
            app.TauEditFieldLabel = uilabel(app.LeftPanel);
            app.TauEditFieldLabel.Text = 'Tau';
            app.TauEditField = uieditfield(app.LeftPanel, 'numeric');
            app.TauEditField.Limits = [0 10000000];
            app.TauEditField.ValueDisplayFormat = '%8.3f';
            app.TauEditField.Editable = 'off';
            app.TauEditField.Value = 1.286;
            app.PostmenstrualAgedaysLabel = uilabel(app.LeftPanel);
            app.PostmenstrualAgedaysLabel.Text = 'Postmenstrual Age (days)';
            app.PMAEditField = uieditfield(app.LeftPanel, 'numeric');
            app.PMAEditField.Limits = [260 440];
            app.PMAEditField.ValueDisplayFormat = '%8.f';
            app.PMAEditField.Editable = 'off';
            app.PMAEditField.Value = 289;
            app.Switch_2 = uiswitch(app.LeftPanel, 'slider');
            app.Switch_2.Items = {'Database', 'User-provided data'};
            app.Switch_2.ValueChangedFcn = createCallbackFcn(app, @Switch_2ValueChanged, true);
            app.Switch_2.Value = 'Database';
            app.DatabaseScan1181EditFieldLabel = uilabel(app.LeftPanel);
            app.DatabaseScan1181EditFieldLabel.Text = 'Database Scan # (1-181)';
            app.UseDatabase = uieditfield(app.LeftPanel, 'numeric');
            app.UseDatabase.Limits = [1 181];
            app.UseDatabase.ValueChangedFcn = createCallbackFcn(app, @UseDatabaseValueChanged, true);
            app.UseDatabase.Value = 1;
            app.PlotMetaboliteRatiosButton = uibutton(app.LeftPanel, 'push');
            app.PlotMetaboliteRatiosButton.ButtonPushedFcn = createCallbackFcn(app, @PlotMetaboliteRatiosButtonPushed, true);
            app.PlotMetaboliteRatiosButton.Text = 'Plot Metabolite Ratios';
            app.PlotEstimatesTrue = uibutton(app.LeftPanel, 'push');
            app.PlotEstimatesTrue.ButtonPushedFcn = createCallbackFcn(app, @PlotEstimatesTruePushed, true);
            app.PlotEstimatesTrue.Text = 'Plot Molality Estimates + Measured Molality Values';
            app.TextArea = uitextarea(app.LeftPanel);
            app.TextArea.Value = {'For Database Scans 1-71:'};
            app.TextArea_2 = uitextarea(app.LeftPanel);
            app.TextArea_2.Value = {'Scans in Database are listed in order of most accurate molality esimtates (1) to least accurate (71)'};
            app.RightPanel = uipanel(app.GridLayout);
            app.PrincipalInterestUIAxes = uiaxes(app.RightPanel);
            title(app.PrincipalInterestUIAxes, '')
            xlabel(app.PrincipalInterestUIAxes, '')
            ylabel(app.PrincipalInterestUIAxes, '')
            app.PrincipalInterestUIAxes_2 = uiaxes(app.RightPanel);
            title(app.PrincipalInterestUIAxes_2, '')
            xlabel(app.PrincipalInterestUIAxes_2, '')
            ylabel(app.PrincipalInterestUIAxes_2, '')
            app.PrincipalInterestUIAxes_3 = uiaxes(app.RightPanel);
            title(app.PrincipalInterestUIAxes_3, '')
            xlabel(app.PrincipalInterestUIAxes_3, '')
            ylabel(app.PrincipalInterestUIAxes_3, '')
            app.PrincipalInterestUIAxes_4 = uiaxes(app.RightPanel);
            title(app.PrincipalInterestUIAxes_4, '')
            xlabel(app.PrincipalInterestUIAxes_4, '')
            ylabel(app.PrincipalInterestUIAxes_4, '')
            app.PrincipalInterestUIAxes_5 = uiaxes(app.RightPanel);
            title(app.PrincipalInterestUIAxes_5, '')
            xlabel(app.PrincipalInterestUIAxes_5, '')
            ylabel(app.PrincipalInterestUIAxes_5, '')
            app.PrincipalInterestUIAxes_6 = uiaxes(app.RightPanel);
            title(app.PrincipalInterestUIAxes_6, '')
            xlabel(app.PrincipalInterestUIAxes_6, '')
            ylabel(app.PrincipalInterestUIAxes_6, '')
            app.PrincipalInterestUIAxes_7 = uiaxes(app.RightPanel);
            title(app.PrincipalInterestUIAxes_7, '')
            xlabel(app.PrincipalInterestUIAxes_7, '')
            ylabel(app.PrincipalInterestUIAxes_7, '')
            app.PrincipalInterestUIAxes_8 = uiaxes(app.RightPanel);
            title(app.PrincipalInterestUIAxes_8, '')
            xlabel(app.PrincipalInterestUIAxes_8, '')
            ylabel(app.PrincipalInterestUIAxes_8, '')
            app.PrincipalInterestUIAxes_9 = uiaxes(app.RightPanel);
            title(app.PrincipalInterestUIAxes_9, '')
            xlabel(app.PrincipalInterestUIAxes_9, '')
            ylabel(app.PrincipalInterestUIAxes_9, '')
            app.PrincipalInterestUIAxes_10 = uiaxes(app.RightPanel);
            title(app.PrincipalInterestUIAxes_10, '')
            xlabel(app.PrincipalInterestUIAxes_10, '')
            ylabel(app.PrincipalInterestUIAxes_10, '')
            app.GraphsLabel = uitextarea(app.RightPanel);
            % Show the figure after all components are created
            app.InfantMRSUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app_source_code

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.InfantMRSUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.InfantMRSUIFigure)
        end
    end
end